classdef GUI_example < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ReadNodeandElementListMenu      matlab.ui.container.Menu
        SaveNodeandElementListMenu      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        GeometryandConditionsTab        matlab.ui.container.Tab
        NodeLabelsSwitch                matlab.ui.control.ToggleSwitch
        NodeLabels                      matlab.ui.control.Label
        ElementLabelsSwitch             matlab.ui.control.ToggleSwitch
        ElementLabels                   matlab.ui.control.Label
        SizeSlider                      matlab.ui.control.Slider
        SizeSliderLabel                 matlab.ui.control.Label
        Button_DeleteLast               matlab.ui.control.Button
        Node2                           matlab.ui.control.NumericEditField
        Node2EditFieldLabel             matlab.ui.control.Label
        Node1                           matlab.ui.control.NumericEditField
        Node1EditFieldLabel             matlab.ui.control.Label
        ElementsLabel                   matlab.ui.control.Label
        GenerateElementButton           matlab.ui.control.Button
        ElementsCreated                 matlab.ui.control.NumericEditField
        ElementsEditFieldLabel          matlab.ui.control.Label
        Y_coor                          matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        X_coor                          matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        NodesLabel                      matlab.ui.control.Label
        GenerateNodeButton              matlab.ui.control.Button
        NodesCreated                    matlab.ui.control.NumericEditField
        NodesEditFieldLabel             matlab.ui.control.Label
        ClearStructure                  matlab.ui.control.Button
        PreProcessLabel                 matlab.ui.control.Label
        ProcessLabel                    matlab.ui.control.Label
        BoundaryConditionsPanel         matlab.ui.container.Panel
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        BCNode                          matlab.ui.control.NumericEditField
        DirectionSwitchLabel            matlab.ui.control.Label
        BCDirectionSwitch               matlab.ui.control.Switch
        TypeSwitchLabel                 matlab.ui.control.Label
        BCTypeSwitch                    matlab.ui.control.Switch
        MagnitudeNormEditFieldLabel     matlab.ui.control.Label
        BCMagnitude                     matlab.ui.control.NumericEditField
        ClearBCs                        matlab.ui.control.Button
        ApplyBCs                        matlab.ui.control.Button
        MaterialPropertiesPanel         matlab.ui.container.Panel
        ElasticityModulus               matlab.ui.control.NumericEditField
        EPaLabel                        matlab.ui.control.Label
        Area                            matlab.ui.control.NumericEditField
        AmLabel                         matlab.ui.control.Label
        PRtoElement                     matlab.ui.control.NumericEditField
        ElementEditFieldLabel           matlab.ui.control.Label
        PRSwitch                        matlab.ui.control.Switch
        AddPR                           matlab.ui.control.Button
        ClearPRs                        matlab.ui.control.Button
        ResetAllButton                  matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        UIAxes                          matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        ColorbarButtonGroup             matlab.ui.container.ButtonGroup
        Jet                             matlab.ui.control.ToggleButton
        Copper                          matlab.ui.control.ToggleButton
        Cool                            matlab.ui.control.ToggleButton
        PostProcessLabel                matlab.ui.control.Label
        ShowRefSwitch_S                 matlab.ui.control.ToggleSwitch
        ShowRefSwitchLabel              matlab.ui.control.Label
        ShowRefSwitch_D                 matlab.ui.control.ToggleSwitch
        ShowRefSwitch_2Label            matlab.ui.control.Label
        ExaggerateSlider                matlab.ui.control.Slider
        ExaggerateSliderLabel           matlab.ui.control.Label
        DispDirecton                    matlab.ui.control.DropDown
        DisplacementabsoluteLabel       matlab.ui.control.Label
        StressLabel                     matlab.ui.control.Label
        UIAxes_S                        matlab.ui.control.UIAxes
        UIAxes_D                        matlab.ui.control.UIAxes
        NodeandElementListsNLELTab      matlab.ui.container.Tab
        NLTable                         matlab.ui.control.Table
        ELTable                         matlab.ui.control.Table
        NodeListNLLabel                 matlab.ui.control.Label
        ElementListELLabel              matlab.ui.control.Label
        BoundaryConditionandMaterialPropertyListsBCsPRsTab  matlab.ui.container.Tab
        BCsTable                        matlab.ui.control.Table
        PRsTable                        matlab.ui.control.Table
        BoundaryConditionsListBCsLabel  matlab.ui.control.Label
        MaterialPropertiesListPRsLabel  matlab.ui.control.Label
        ExtendedNodeListENLTab          matlab.ui.container.Tab
        ENLTable                        matlab.ui.control.Table
        ExtendedNodeListENLLabel        matlab.ui.control.Label
        AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab  matlab.ui.container.Tab
        AverageMechanicalPropertiesofTypicalEngineeringMaterialsLabel  matlab.ui.control.Label
        Table_ceramics                  matlab.ui.control.Table
        Table_polymers                  matlab.ui.control.Table
        Table_metals                    matlab.ui.control.Table
        MetalsLabel                     matlab.ui.control.Label
        PolymersatroomtemperatureLabel  matlab.ui.control.Label
        CeramicsandGlassesLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    
    properties (Access = public)
        NL = []                 % Node List
        EL = []                 % Element List
        BCs = []                % Boundary Conditions List (Type, Disp. and Force), Dimensions = (Number of BCs) x (3*PD)
        ENL = []                % Extended Node List
        DOFs = 0                % Degrees of Freedom
        DOCs = 0                % Degrees of Constraint
        NoN = 0                 % Number of Nodes 
        NoE = 0                 % Number of Elements
        NodeSwitch = "On"       % Node flag switch
        NodeSize = 20           % Size of the Nodes
        ElementSwitch = "On"    %...
        ElementSize = 20        %...
        NL_memory = [0]          % Which order (absolute) the nodes were added
        EL_memory = [0]          % Which order (absolute) the elements were added
        feat_order = 1          % Order counter (adding a feature --> feat_order += 1, delete a feature --> feat_order -= 1)
        
        BC_Node = 1             % Node to be assigned BC
        BC_Direction = [1 0]    % Direction of the BC to be assigned
        BC_Type = 1             % Type of the BC to be assigned
        BC_Magnitude = 0        % Magnitude of the BC to be assigned
        E = 0                   % Young's Modulus
        A = 0                   % Cross sectional area
        PR_Element = 1          % No. of chosen element to assign material property
        PR_option = "All"       % Option to assign material property to a chosen element OR all elements (all/chosen)
        PRs = []                % Material Properties [NoE x 2], column1 = E and column2 = A values
        colorMap = jet          % colorMap for plotting
        
        Fp = []                 % Assembled forces
        Up = []                 % Assembled displacements
        Def_mag = 0             % Exaggeration magnitude in displacement plot
        Ref_S = "On"            % Closes/opens reference figure in stress plot
        Ref_D = "On"            % Closes/opens reference figure in displacement plot
        isrun = "off"           % Value shows if algorithm is run or not
        Disp_Direction = "U_t"  % Displacement direction in the plot
        
        
    end
    
    methods (Access = public)
        
        function results = updateAxes(app)
            
                                                          %%%%%%%%%%%%%%%%% Updates/creates the pre-process plot %%%%%%%%%%%%%%%%%
            
            cla(app.UIAxes);
            hold(app.UIAxes,"on");
                        
            for i = 1: app.NoE
                H=plot(app.UIAxes,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                H.Color(4)=0.2;
            end
            
            
            switch app.NodeSwitch
                
                case "On"
                    
                    for i = 1:app.NoN
                        % Plots Nodes and label number
                        
                        plot(app.UIAxes, app.NL(i,1), app.NL(i,2),'o','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                        grid(app.UIAxes,'on')
                        axis(app.UIAxes, 'square')
                        text(app.UIAxes,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                        
                        x_min = min(app.NL(:,1))-0.25*(max(app.NL(:,1))-min(app.NL(:,1))+0.1);
                        x_max = max(app.NL(:,1))+0.25*(max(app.NL(:,1))-min(app.NL(:,1))+0.1);
                        y_min = min(app.NL(:,2))-0.25*(max(app.NL(:,2))-min(app.NL(:,2))+0.1);
                        y_max = max(app.NL(:,2))+0.25*(max(app.NL(:,2))-min(app.NL(:,2))+0.1);
                        
                        xlim(app.UIAxes,[x_min,x_max])
                        ylim(app.UIAxes,[y_min,y_max])
                        w = 0.16*app.NodeSize/20; % width of triangle for BCs (scales with the nodes)
                        sx = abs(x_max-x_min); % how much is x axis distorted wrt zoom
                        sy = abs(y_max-y_min); % how much is y axis is distorted wrt zoom
                        
                        if app.BCs(i,1) == -1
                            
                            if app.BCs(i,3) == 0
                                x = [app.NL(i,1)-(w*sx)/2 app.NL(i,1) app.NL(i,1)-(w*sx)/2]; %x coordinates of vertices scaled wrt plot limits to maintain shape
                                y = [app.NL(i,2)-(w*sy)/2 app.NL(i,2) app.NL(i,2)+(w*sy)/2]; %y coordinates of vertices scaled wrt plot limits to maintain shape
                                patch(app.UIAxes, x, y, 'red', 'FaceColor', 'blue','FaceAlpha', 0.3)
                            else
                                
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), append('\fontsize{35}\rightarrow', '{\fontsize{14}', num2str(app.BCs(i,3)), ' m}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                            end
                        
                        end
                        
                        if app.BCs(i,2) == -1
                            
                            if app.BCs(i,4) == 0
                                x = [app.NL(i,1)-(w*sx)/2 app.NL(i,1) app.NL(i,1)+(w*sx)/2]; %x coordinates of vertices scaled wrt plot limits to maintain shape
                                y = [app.NL(i,2)-(w*sy)/2 app.NL(i,2) app.NL(i,2)-(w*sy)/2]; %y coordinates of vertices scaled wrt plot limits to maintain shape
                                patch(app.UIAxes, x, y, 'red', 'FaceColor', 'blue','FaceAlpha', 0.3)
                                
                            else
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{14}', num2str(-1*app.BCs(i,4)),' m'),'\fontsize{35}\downarrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            end
                        end
                        
                        if (app.BCs(i,5) ~= 0) && (app.BCs(i,1) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), append('\fontsize{35}\rightarrow', '{\fontsize{14}', num2str(app.BCs(i,5)), ' N}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                        end
                        
                        if (app.BCs(i,6) ~= 0) && (app.BCs(i,2) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{14}', num2str(-1*app.BCs(i,6)),' N'),'\fontsize{35}\downarrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                        end
                        
                    end
            end
            
            switch app.ElementSwitch
                
                case "On"
                    
                    for i = 1:app.NoE
                        
                        plot(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2, (app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,'o','MarkerSize',app.ElementSize,'MarkerEdgeColor','k','MarkerFaceColor',[1,1,0])
                        text(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2, (app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,num2str(i),'Color','k','FontSize',12,'HorizontalAlignment','center')
                    end
            
            end

            hold(app.UIAxes,"off");
            
        end
        
        function results2 = process(app)
            
                                                      %%%%%%%%%%%%%%%%% Initiates the main body of calculations (apart from assigning BCs) %%%%%%%%%%%%%%%%%
                                                      
                                                  %%%% Need to add code (or error checks) to clear/update final values (or stop the execution to display error) %%%%
            
            format long
            
            K = assemble_stiffness(app.ENL, app.EL, app.NL, app.PRs);
            
            Fp = assemble_forces(app.ENL, app.NL);
            
            Up = assemble_displacements(app.ENL, app.NL);
            
            Kpu = K(1:app.DOFs,1:app.DOFs);
            Kpp = K(1:app.DOFs,app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs,1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);
            
            F = Fp - Kpp * Up;
            
            Uu = inv(Kpu)*F;
            
            Fu = Kuu*Uu + Kup*Up;
            
            app.ENL = update_nodes(app.ENL,Uu,app.NL,Fu);
            
            NoN = 1:app.NoN;
            app.ENLTable.Data = string([NoN(:) app.ENL]);
        end
        
        function results3 = Postprocess(app)
            
                                                         %%%%%%%%%%%%%%%%% Displays and manipulates stress and distribution plots %%%%%%%%%%%%%%%%%
            
            %%%% draw the undeformed configuration %%%%
            
            %clears plots, sets colormaps
            cla(app.UIAxes_S);
            cla(app.UIAxes_D);
            
            colormap(app.UIAxes_S,app.colorMap);
            
            colormap(app.UIAxes_D,app.colorMap);
            
            hold(app.UIAxes_S,"on");
            hold(app.UIAxes_D,"on");
            grid(app.UIAxes_S,'on')
            grid(app.UIAxes_D,'on')
            
            % plot undeformed elements and nodes
            switch app.Ref_S
                
                case "On"
                    
                    % plot elements
                    for i = 1: app.NoE
                        H_S=plot(app.UIAxes_S, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                        H_S.Color(4)=0.2;
                                 
                    end
                    
                    % plot nodes
                    for i = 1 : app.NoN
                        plot(app.UIAxes_S, app.NL(i,1),app.NL(i,2),'o','MarkerSize',12,'MarkerEdgeColor','k','MarkerFaceColor','r')
                    end
                    
            end
            
            switch app.Ref_D
                
                case "On"
                    
                    % plot elements
                    for i = 1: app.NoE
                        
                        H_D=plot(app.UIAxes_D, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                        H_D.Color(4)=0.2;
                        
                    end
                    
                    % plot nodes
                    for i = 1 : app.NoN
                        plot(app.UIAxes_D, app.NL(i,1),app.NL(i,2),'o','MarkerSize',12,'MarkerEdgeColor','k','MarkerFaceColor','r')
                    end
                    
            end
            
            
            % plots deformed elements with stress and displacement distribution
            for i = 1 : app.NoE
                
                x = [app.ENL(app.EL(i,1),1)+(10^(app.Def_mag))*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+(10^(app.Def_mag))*app.ENL(app.EL(i,2),9)];
                y = [app.ENL(app.EL(i,1),2)+(10^(app.Def_mag))*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+(10^(app.Def_mag))*app.ENL(app.EL(i,2),10)];
                z = zeros(size(x));
                sigma = app.PRs(i,1) * (sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)...
                    /(sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                
                colS = [sigma sigma];
                surface(app.UIAxes_S, [x;x],[y;y],[z;z],[colS;colS],'facecol','no','edgecol','interp','linew',4);
                
                if app.Disp_Direction == "U_t"
                    colD = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
                    surface(app.UIAxes_D, [x;x],[y;y],[z;z],[colD;colD],'facecol','no','edgecol','interp','linew',4);
                
                elseif app.Disp_Direction == "U_x"
                    
                    colDx = [abs(app.ENL(app.EL(i,1),9)) abs(app.ENL(app.EL(i,2),9))];
                    surface(app.UIAxes_D, [x;x],[y;y],[z;z],[colDx;colDx],'facecol','no','edgecol','interp','linew',4);
                    
                elseif app.Disp_Direction == "U_y"   
                    
                    colDy = [abs(app.ENL(app.EL(i,1),10)) abs(app.ENL(app.EL(i,2),10))];
                    surface(app.UIAxes_D, [x;x],[y;y],[z;z],[colDy;colDy],'facecol','no','edgecol','interp','linew',4);
                    
                end

            end
            
            colorbar(app.UIAxes_S);
            colorbar(app.UIAxes_D);
            
            hold(app.UIAxes_S,"off");
            hold(app.UIAxes_D,"off");
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %%% Fills in the engineering materials property table %%%
            
            format long
            
            app.Table_metals.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Yield Strength \nσ_y [MPa]'); sprintf('Ultimate Strength\nσ_u [MPa]')};
            app.Table_polymers.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Yield Strength \nσ_y [MPa]'); sprintf('Fracture Strength\nσ_f [MPa]')};
            app.Table_ceramics.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Yield Strength \n(Tension)\nσ_y [MPa]'); sprintf('Yield Strength\n(Compression)\nσ_u [MPa]')};
            
            app.Table_metals.Data = readtable('Mech. Properties List.xlsx','Range','A1:D17');
            
            t2 = readtable('Mech. Properties List.xlsx','Range','F1:I14');
            t2{2, 3} = {'-'};
            t2{4, 3} = {'-'};
            t2{5, 3} = {'-'};
            t2{7, 3} = {'-'};
            t2{11, 3} = {'-'};
            app.Table_polymers.Data = t2;
            
            t3 = readtable('Mech. Properties List.xlsx','Range','K1:N12');
            t3{2, 4} = {'-'};
            app.Table_ceramics.Data = t3;
            
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            
            %%%% Draws node, updates node list %%%%
            
            nl = [app.X_coor.Value, app.Y_coor.Value];
            
            if app.NoN > 0 && (sum(ismember(app.NL, nl,"rows")) == 1)
                uialert(app.UIFigure,"Node already exists","Input Error")
                
            else
                
                app.NL = [app.NL; nl];
                
                app.NoN = size(app.NL,1);
                
                app.NodesCreated.Value = app.NoN;
                
                app.BCs = [app.BCs; [1 1 0 0 0 0]];
                
                app.NL_memory = [app.NL_memory; app.feat_order];
                
                app.feat_order = app.feat_order + 1;
                
                %app.ENL = [app.BCs; [1 1 0 0 0 0]];
                
                NoN = 1:app.NoN;
                
                app.NLTable.Data = [NoN(:) app.NL];
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) zeros(app.NoN, 12)]);

                updateAxes(app);
            end

        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
            
            %%%% Draws element, updates element list %%%%
            
            el = [app.Node1.Value, app.Node2.Value];
            
            if app.NoN < 2
                
                uialert(app.UIFigure,"You need at least 2 nodes to create an element","Input Error")
            
            elseif app.NoE > 0 && (sum(ismember(app.EL, el, "rows")) == 1 || sum(ismember(app.EL, [app.Node2.Value, app.Node1.Value], "rows")) == 1)
                
                uialert(app.UIFigure,"Element already exists","Input Error")
            
            else
                
                app.EL = [app.EL; el];
                
                app.NoE = size(app.EL,1);
                
                app.ElementsCreated.Value = app.NoE;
                
                app.PRs = [app.PRs; [0 0]];
                
                app.EL_memory = [app.EL_memory; app.feat_order];
                
                app.feat_order = app.feat_order + 1;
                
                NoE = 1:app.NoE;
                
                app.ELTable.Data = [NoE(:) app.EL];
                app.PRsTable.Data = [NoE(:) app.PRs];
                
                updateAxes(app);
            end
        end

        % Value changed function: SizeSlider
        function SizeSliderValueChanged(app, event)
            
            %%%% Adjusts element and node size %%%%
            
            app.NodeSize = app.SizeSlider.Value;
            app.ElementSize = app.SizeSlider.Value;
            
            updateAxes(app);
        end

        % Selection changed function: ColorbarButtonGroup
        function ColorbarButtonGroupSelectionChanged(app, event)
            
            %%%% Color map selection buttons %%%%
            
            SelectedButton = app.ColorbarButtonGroup.SelectedObject;
            
            switch SelectedButton
                
                case app.Jet
                    app.colorMap = jet;
                    colormap(app.UIAxes_S,app.colorMap);
                    colormap(app.UIAxes_D,app.colorMap);
                    
                case app.Copper
                    app.colorMap = copper;
                    colormap(app.UIAxes_S,app.colorMap);
                    colormap(app.UIAxes_D,app.colorMap);
                
                case app.Cool
                    app.colorMap = cool;
                    colormap(app.UIAxes_S,app.colorMap);
                    colormap(app.UIAxes_D,app.colorMap);
            end
            
            if app.isrun == "on"
                Postprocess(app);
            end
            
        end

        % Value changed function: NodeLabelsSwitch
        function NodeLabelsSwitchValueChanged(app, event)
            
            %%%% Turns on and off Node Labels %%%%
            
            app.NodeSwitch = app.NodeLabelsSwitch.Value;
            
            if app.NoN > 0
                
                updateAxes(app);
            end
            
            
        end

        % Value changed function: ElementLabelsSwitch
        function ElementLabelsSwitchValueChanged(app, event)
            
            %%%% Turns on and off Element Labels %%%%
            
            app.ElementSwitch = app.ElementLabelsSwitch.Value;
            
            if app.NoE > 0
                
                updateAxes(app);
            end
        end

        % Button pushed function: ClearStructure
        function ClearStructureButtonPushed(app, event)
            
            %%%% Clears all Nodes and Elements %%%%
            
            app.NL = [];
            app.EL = [];
            
            app.NL_memory = [0];
            app.EL_memory = [0];
            app.feat_order = 1;
            
            app.NoN = 0;
            app.NoE = 0;
            app.NodesCreated.Value = 0;
            app.ElementsCreated.Value = 0;
            
            app.BCs = [];
            app.PRs = [];
            app.ENL = [];
            
            app.NLTable.Data = [];
            app.ELTable.Data = [];
            app.PRsTable.Data = [];
            app.BCsTable.Data = [];
            app.ENLTable.Data = [];
            
            updateAxes(app);
        end

        % Value changed function: BCNode
        function BCNodeValueChanged(app, event)
            
            %%%% Node to be assigned a BC %%%%
            
            app.BC_Node = app.BCNode.Value;
            
        end

        % Value changed function: BCDirectionSwitch
        function BCDirectionSwitchValueChanged(app, event)
            
            %%%% Direction of the BC to be assigned %%%%
            
            if app.BCDirectionSwitch.Value == "X"
                app.BC_Direction = [1,0];
            
            else
                app.BC_Direction = [0,1];
                
            end
            
        end

        % Value changed function: BCTypeSwitch
        function BCTypeSwitchValueChanged(app, event)
            
            %%% Type of the BC to be assigned %%%%
            
            if app.BCTypeSwitch.Value == "Force"
                app.BC_Type = 1;
                
            else
                app.BC_Type = -1;
                
            end
            
        end

        % Value changed function: BCMagnitude
        function BCMagnitudeValueChanged(app, event)
            
            %%%% Magnitude of the BC to be assigned %%%%
            
            app.BC_Magnitude = app.BCMagnitude.Value;
            
        end

        % Button pushed function: ClearBCs
        function ClearBCsButtonPushed(app, event)
           
            %%%% Button clears all BCs %%%% 
            app.BCs = [];
            app.ENL = [];

            app.BCsTable.Data = [];
            app.ENLTable.Data = [];
            
            for i = 1:app.NoN
                
                app.BCs(i,1:6)=[1 1 0 0 0 0];
            end
            updateAxes(app);
        end

        % Button pushed function: ApplyBCs
        function ApplyBCsButtonPushed(app, event)
            
            %%%% Assigns the specified BC %%%%
            
            if app.NoN < app.BC_Node
                uialert(app.UIFigure,"Node does not exist","Input Error")
                
            else
                [M,I]=max(app.BC_Direction); %Finds whether X or Y indice is entered as BC and gives it in I variable (M gives max value of 1x2 direction matrix which is 1)
                
                app.BCs(app.BC_Node,I) = app.BC_Type;
                if app.BC_Type == 1
                    
                    app.BCs(app.BC_Node,5:6) = app.BCs(app.BC_Node,5:6)+(app.BC_Magnitude)*(app.BC_Direction);
                    app.BCs(app.BC_Node,3:4) = app.BCs(app.BC_Node,3:4).*flip(app.BC_Direction);
                else
                    
                    app.BCs(app.BC_Node,3:4) = app.BCs(app.BC_Node,3:4)+(app.BC_Magnitude)*(app.BC_Direction);
                    app.BCs(app.BC_Node,5:6) = app.BCs(app.BC_Node,5:6).*flip(app.BC_Direction);
                end
                [app.ENL , app.DOFs, app.DOCs] = assign_BCs(app.NL,app.BCs);
                
                NoN = 1:app.NoN;
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) app.ENL]);
                
                updateAxes(app);
                
            end
            
        end

        % Value changed function: ElasticityModulus
        function ElasticityModulusValueChanged(app, event)
            
            %%%% Young's Modulus of chosen (OR ALL) element %%%%
            
            app.E = app.ElasticityModulus.Value;
            
        end

        % Value changed function: Area
        function AreaValueChanged(app, event)
            
            %%%% Cross sectional area of chosen (OR ALL) element %%%%
            
            app.A = app.Area.Value;
            
        end

        % Value changed function: PRtoElement
        function PRtoElementValueChanged(app, event)
            
            %%%% No. of chosen element to assign material property %%%%
            
            app.PR_Element = app.PRtoElement.Value;
            
        end

        % Value changed function: PRSwitch
        function PRSwitchValueChanged(app, event)
            
            %%%% Option to assign material property to a chosen element OR all elements %%%%
            
            app.PR_option = app.PRSwitch.Value;
            
        end

        % Button pushed function: AddPR
        function AddPRButtonPushed(app, event)
            
            %%%% Checks whether all or a specific element is chosen, then assigns the typed property value %%%%
            
            if app.E >3.21e13
                uialert(app.UIFigure,"Please enter a lower Young's Modulus value. The " + ...
                    "material with the highest know E value is Carbyne, which is 32100 GPa","Input Error")
            
            else

                if app.PR_option == "All"
                    for i = 1:app.NoE
                        
                        app.PRs(i,:) = [app.E app.A];
                        
                    end
                    
                else
                    app.PRs(app.PR_Element,:) = [app.E app.A];
                    
                end
                NoE = 1:app.NoE;
                app.PRsTable.Data = [NoE(:) app.PRs];
            end

            
        end

        % Button pushed function: ClearPRs
        function ClearPRsButtonPushed(app, event)
            
            %%%% Button clears all material properties %%%%
            
            app.PRs = zeros(app.NoE,2);
            app.PRsTable.Data = [];

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            %%%% Runs the process algorithm, lights the lamp plots stress and displacement %%%%
            %%%% Need to add many ERROR CHECKS here!!!%%%%
            
            if ismember(0,app.PRs)
                uialert(app.UIFigure,"One or more elements does not have material property","Input Error")
            else
                process(app);
                app.Lamp.Color = [0 1 0];
                
                app.isrun = "on";
                Postprocess(app);
            end
            
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            
            %%%% Resets the structure, BCs, PRs and calculations %%%%
            %%%% NOT FINALIZED %%%%
            
            app.NL = [];
            app.EL = [];
            
            app.NL_memory = [0];
            app.EL_memory = [0];
            app.feat_order = 1;
            
            app.NoN = 0;
            app.NoE = 0;
            app.NodesCreated.Value = 0;
            app.ElementsCreated.Value = 0;
            
            app.BCs = [];
            app.PRs = [];
            app.ENL = [];
            
            app.NLTable.Data = [];
            app.ELTable.Data = [];
            app.PRsTable.Data = [];
            app.BCsTable.Data = [];
            app.ENLTable.Data = [];
            
            app.isrun = "off";
            
            updateAxes(app);
            Postprocess(app);
            
            app.Lamp.Color = [0.94 0.94 0.94];
        end

        % Value changed function: ExaggerateSlider
        function ExaggerateSliderValueChanged(app, event)
            
            %%%% Adjusts exagerration of deflection in plot %%%%
            
            app.Def_mag = app.ExaggerateSlider.Value;
            
            if app.isrun == "on"
                Postprocess(app);
            end
        end

        % Value changed function: ShowRefSwitch_S
        function ShowRefSwitch_SValueChanged(app, event)
            
            %%%% Closes/opens reference figure in stress plot %%%%
            
            app.Ref_S = app.ShowRefSwitch_S.Value;
            
            if app.isrun == "on"
                Postprocess(app);
            end
        end

        % Value changed function: ShowRefSwitch_D
        function ShowRefSwitch_DValueChanged(app, event)
            
            %%%% Closes/opens reference figure in displacement plot %%%%
            
            app.Ref_D = app.ShowRefSwitch_D.Value;
            
            if app.isrun == "on"
                Postprocess(app);
            end
            
        end

        % Value changed function: DispDirecton
        function DispDirectonValueChanged(app, event)

            %%%% Changes the direction of the shown displacement type (U_t, U_x or U_y) %%%%
            
            app.Disp_Direction = app.DispDirecton.Value;
            
            if app.isrun == "on"
                                
                Postprocess(app);
                
            end
            
        end

        % Button pushed function: Button_DeleteLast
        function Button_DeleteLastPushed(app, event)
            
            if app.NL_memory(end) > app.EL_memory(end)
                
                app.NL_memory(end,:) = [];
                
                app.NL(end,:) = [];
                
                app.NoN = size(app.NL,1);
                
                app.NodesCreated.Value = app.NoN;
                
                app.BCs(end,:) = [];
                
                app.NLTable.Data(end,:) = [];
                app.BCsTable.Data(end,:) = [];
                app.ENLTable.Data(end,:) = [];
                
                
            elseif app.NL_memory(end) < app.EL_memory(end)
                app.EL_memory(end,:) = [];
                
                app.EL(end,:) = [];
                
                app.NoE = size(app.EL,1);
                
                app.ElementsCreated.Value = app.NoE;
                
                app.PRs(end,:) = [];
                
                app.ELTable.Data(end,:) = [];
                app.PRsTable.Data(end,:) = [];
            
            else
                uialert(app.UIFigure,"There are no more features to delete.","Input Error")
                
            end
                
            app.feat_order = app.feat_order - 1;
            updateAxes(app);
            
        end

        % Menu selected function: ReadNodeandElementListMenu
        function ReadNodeandElementListMenuSelected(app, event)
            [inputfile,~] = uigetfile({'*.xlsx','*.slx'},'Select File to Open');
            
            if ~isequal(inputfile,0)
                
                ResetAllButtonPushed(app)
                
                nl = readmatrix(inputfile,'Range','A:B');
                el = readmatrix(inputfile,'Range','C:D');
                
                for i = 1: size(nl,1)
                    if ~isnan(nl(i,:))
                        
                        app.NL = [app.NL; nl(i,:)];
                        app.BCs = [app.BCs; [1 1 0 0 0 0]];
                        
                        app.NL_memory = [app.NL_memory; app.feat_order];
                        app.feat_order = app.feat_order + 1;
                    end
                end
                
                for i = 1: size(el,1)
                    if ~isnan(el(i,:))
                        
                        app.PRs = [app.PRs; [0 0]];
                        app.EL = [app.EL; el(i,:)];
                        app.EL_memory = [app.EL_memory; app.feat_order];
                        app.feat_order = app.feat_order + 1;
                    end
                    
                end
                
                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);
                
                NoN = 1:app.NoN;
                NoE = 1:app.NoE;
                
                app.NLTable.Data = [NoN(:) app.NL];
                app.ELTable.Data = [NoE(:) app.EL];
                app.PRsTable.Data = [NoE(:) app.PRs];
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) app.ENL]);
                
                updateAxes(app);
            end
        end

        % Menu selected function: SaveNodeandElementListMenu
        function SaveNodeandElementListMenuSelected(app, event)
            
            % Creates and excel file and writes the NL and EL of the current structure into the file
            
            [outputfile,~] = uiputfile({'*.xlsx','*.slx'},'Save File');
            
            if ~isequal(outputfile,0)
                writecell({'Node x' 'Node y' 'Element N1' 'Element N2'}, append(outputfile,'.xlsx'),'Range','A1');
                writematrix(app.NL, append(outputfile,'.xlsx'),'Range','A2');
                writematrix(app.EL, append(outputfile,'.xlsx'),'Range','C2');
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FEM';
            app.UIFigure.Icon = 'm_bilkent.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ReadNodeandElementListMenu = uimenu(app.FileMenu);
            app.ReadNodeandElementListMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadNodeandElementListMenuSelected, true);
            app.ReadNodeandElementListMenu.Text = 'Read Node and Element List';
            app.SaveNodeandElementListMenu = uimenu(app.FileMenu);
            app.SaveNodeandElementListMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveNodeandElementListMenuSelected, true);
            app.SaveNodeandElementListMenu.Text = 'Save Node and Element List';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeometryandConditionsTab = uitab(app.TabGroup);
            app.GeometryandConditionsTab.Title = 'Geometry and Conditions';
            app.NodeLabelsSwitch = uiswitch(app.GeometryandConditionsTab, 'toggle');
            app.NodeLabelsSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelsSwitchValueChanged, true);
            app.NodeLabelsSwitch.Value = 'On';
            app.NodeLabels = uilabel(app.GeometryandConditionsTab);
            app.NodeLabels.Text = {'Node '; 'Labels'};
            app.ElementLabelsSwitch = uiswitch(app.GeometryandConditionsTab, 'toggle');
            app.ElementLabelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelsSwitchValueChanged, true);
            app.ElementLabelsSwitch.Value = 'On';
            app.ElementLabels = uilabel(app.GeometryandConditionsTab);
            app.ElementLabels.Text = {'Element '; 'Labels'};
            app.SizeSlider = uislider(app.GeometryandConditionsTab);
            app.SizeSlider.Limits = [1 100];
            app.SizeSlider.ValueChangedFcn = createCallbackFcn(app, @SizeSliderValueChanged, true);
            app.SizeSlider.Value = 20;
            app.SizeSliderLabel = uilabel(app.GeometryandConditionsTab);
            app.SizeSliderLabel.Text = 'Size';
            app.Button_DeleteLast = uibutton(app.GeometryandConditionsTab, 'push');
            app.Button_DeleteLast.ButtonPushedFcn = createCallbackFcn(app, @Button_DeleteLastPushed, true);
            app.Button_DeleteLast.Text = 'Delete Last Feature';
            app.Node2 = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.Node2EditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node1 = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.Node1EditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.ElementsLabel = uilabel(app.GeometryandConditionsTab);
            app.ElementsLabel.Text = 'Elements';
            app.GenerateElementButton = uibutton(app.GeometryandConditionsTab, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Text = 'Generate Element';
            app.ElementsCreated = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.ElementsCreated.Editable = 'off';
            app.ElementsEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.Y_coor = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.YLabel = uilabel(app.GeometryandConditionsTab);
            app.YLabel.Text = 'Y';
            app.X_coor = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.XLabel = uilabel(app.GeometryandConditionsTab);
            app.XLabel.Text = 'X';
            app.NodesLabel = uilabel(app.GeometryandConditionsTab);
            app.NodesLabel.Text = 'Nodes';
            app.GenerateNodeButton = uibutton(app.GeometryandConditionsTab, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Text = {'Generate Node'; ''};
            app.NodesCreated = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.NodesCreated.Editable = 'off';
            app.NodesEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.NodesEditFieldLabel.Text = '#Nodes';
            app.ClearStructure = uibutton(app.GeometryandConditionsTab, 'push');
            app.ClearStructure.ButtonPushedFcn = createCallbackFcn(app, @ClearStructureButtonPushed, true);
            app.ClearStructure.Text = 'Clear Structure';
            app.PreProcessLabel = uilabel(app.GeometryandConditionsTab);
            app.PreProcessLabel.Text = 'Pre-Process';
            app.ProcessLabel = uilabel(app.GeometryandConditionsTab);
            app.ProcessLabel.Text = 'Process';
            app.BoundaryConditionsPanel = uipanel(app.GeometryandConditionsTab);
            app.BoundaryConditionsPanel.Title = 'Boundary Conditions';
            app.NodeNumberEditFieldLabel = uilabel(app.BoundaryConditionsPanel);
            app.NodeNumberEditFieldLabel.Text = 'Node Number';
            app.BCNode = uieditfield(app.BoundaryConditionsPanel, 'numeric');
            app.BCNode.ValueChangedFcn = createCallbackFcn(app, @BCNodeValueChanged, true);
            app.BCNode.Value = 1;
            app.DirectionSwitchLabel = uilabel(app.BoundaryConditionsPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.BCDirectionSwitch = uiswitch(app.BoundaryConditionsPanel, 'slider');
            app.BCDirectionSwitch.Items = {'X', 'Y'};
            app.BCDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @BCDirectionSwitchValueChanged, true);
            app.BCDirectionSwitch.Value = 'X';
            app.TypeSwitchLabel = uilabel(app.BoundaryConditionsPanel);
            app.TypeSwitchLabel.Text = 'Type';
            app.BCTypeSwitch = uiswitch(app.BoundaryConditionsPanel, 'slider');
            app.BCTypeSwitch.Items = {'Force', 'Displacement'};
            app.BCTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @BCTypeSwitchValueChanged, true);
            app.BCTypeSwitch.Value = 'Force';
            app.MagnitudeNormEditFieldLabel = uilabel(app.BoundaryConditionsPanel);
            app.MagnitudeNormEditFieldLabel.Text = 'Magnitude [N or m]';
            app.BCMagnitude = uieditfield(app.BoundaryConditionsPanel, 'numeric');
            app.BCMagnitude.ValueChangedFcn = createCallbackFcn(app, @BCMagnitudeValueChanged, true);
            app.ClearBCs = uibutton(app.BoundaryConditionsPanel, 'push');
            app.ClearBCs.ButtonPushedFcn = createCallbackFcn(app, @ClearBCsButtonPushed, true);
            app.ClearBCs.Text = 'Clear BCs';
            app.ApplyBCs = uibutton(app.BoundaryConditionsPanel, 'push');
            app.ApplyBCs.ButtonPushedFcn = createCallbackFcn(app, @ApplyBCsButtonPushed, true);
            app.ApplyBCs.Text = 'Apply';
            app.MaterialPropertiesPanel = uipanel(app.GeometryandConditionsTab);
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.ElasticityModulus = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.ElasticityModulus.ValueChangedFcn = createCallbackFcn(app, @ElasticityModulusValueChanged, true);
            app.EPaLabel = uilabel(app.MaterialPropertiesPanel);
            app.EPaLabel.Text = 'E [Pa]';
            app.Area = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Area.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.AmLabel = uilabel(app.MaterialPropertiesPanel);
            app.AmLabel.Text = 'A [m²]';
            app.PRtoElement = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.PRtoElement.ValueChangedFcn = createCallbackFcn(app, @PRtoElementValueChanged, true);
            app.PRtoElement.Value = 1;
            app.ElementEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.ElementEditFieldLabel.Text = 'Element';
            app.PRSwitch = uiswitch(app.MaterialPropertiesPanel, 'slider');
            app.PRSwitch.Items = {'All', 'Chosen'};
            app.PRSwitch.ValueChangedFcn = createCallbackFcn(app, @PRSwitchValueChanged, true);
            app.PRSwitch.Value = 'All';
            app.AddPR = uibutton(app.MaterialPropertiesPanel, 'push');
            app.AddPR.ButtonPushedFcn = createCallbackFcn(app, @AddPRButtonPushed, true);
            app.AddPR.Text = 'Add';
            app.ClearPRs = uibutton(app.MaterialPropertiesPanel, 'push');
            app.ClearPRs.ButtonPushedFcn = createCallbackFcn(app, @ClearPRsButtonPushed, true);
            app.ClearPRs.Text = 'Clear Properties';
            app.ResetAllButton = uibutton(app.GeometryandConditionsTab, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.RUNButton = uibutton(app.GeometryandConditionsTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Lamp = uilamp(app.GeometryandConditionsTab);
            app.UIAxes = uiaxes(app.GeometryandConditionsTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ColorbarButtonGroup = uibuttongroup(app.ResultsTab);
            app.ColorbarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorbarButtonGroupSelectionChanged, true);
            app.ColorbarButtonGroup.Title = 'Colorbar';
            app.Jet = uitogglebutton(app.ColorbarButtonGroup);
            app.Jet.Icon = 'm_jet.png';
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Copper = uitogglebutton(app.ColorbarButtonGroup);
            app.Copper.Icon = 'm_copper.png';
            app.Copper.Text = '';
            app.Cool = uitogglebutton(app.ColorbarButtonGroup);
            app.Cool.Icon = 'm_cool.png';
            app.Cool.Text = '';
            app.PostProcessLabel = uilabel(app.ResultsTab);
            app.PostProcessLabel.Text = 'Post-Process';
            app.ShowRefSwitch_S = uiswitch(app.ResultsTab, 'toggle');
            app.ShowRefSwitch_S.ValueChangedFcn = createCallbackFcn(app, @ShowRefSwitch_SValueChanged, true);
            app.ShowRefSwitch_S.Value = 'On';
            app.ShowRefSwitchLabel = uilabel(app.ResultsTab);
            app.ShowRefSwitchLabel.Text = 'Show Ref.';
            app.ShowRefSwitch_D = uiswitch(app.ResultsTab, 'toggle');
            app.ShowRefSwitch_D.ValueChangedFcn = createCallbackFcn(app, @ShowRefSwitch_DValueChanged, true);
            app.ShowRefSwitch_D.Value = 'On';
            app.ShowRefSwitch_2Label = uilabel(app.ResultsTab);
            app.ShowRefSwitch_2Label.Text = 'Show Ref.';
            app.ExaggerateSlider = uislider(app.ResultsTab);
            app.ExaggerateSlider.Limits = [-2 3];
            app.ExaggerateSlider.MajorTicks = [-2 -1 0 1 2 3];
            app.ExaggerateSlider.MajorTickLabels = {'0.01', '0.1', '1', '10', '100', '1000'};
            app.ExaggerateSlider.ValueChangedFcn = createCallbackFcn(app, @ExaggerateSliderValueChanged, true);
            app.ExaggerateSlider.MinorTicks = [];
            app.ExaggerateSliderLabel = uilabel(app.ResultsTab);
            app.ExaggerateSliderLabel.Text = 'Exaggerate';
            app.DispDirecton = uidropdown(app.ResultsTab);
            app.DispDirecton.Items = {'U_t', 'U_x', 'U_y'};
            app.DispDirecton.ValueChangedFcn = createCallbackFcn(app, @DispDirectonValueChanged, true);
            app.DispDirecton.Value = 'U_t';
            app.DisplacementabsoluteLabel = uilabel(app.ResultsTab);
            app.DisplacementabsoluteLabel.Text = 'Displacement (absolute)';
            app.StressLabel = uilabel(app.ResultsTab);
            app.StressLabel.Text = 'Stress';
            app.UIAxes_S = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes_S, 'X')
            ylabel(app.UIAxes_S, 'Y')
            zlabel(app.UIAxes_S, 'Z')
            app.UIAxes_D = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes_D, 'X')
            ylabel(app.UIAxes_D, 'Y')
            zlabel(app.UIAxes_D, 'Z')
            app.NodeandElementListsNLELTab = uitab(app.TabGroup);
            app.NodeandElementListsNLELTab.Title = 'Node and Element Lists (NL & EL)';
            app.NLTable = uitable(app.NodeandElementListsNLELTab);
            app.NLTable.ColumnName = {'Node No.'; 'X'; 'Y'};
            app.NLTable.RowName = {};
            app.ELTable = uitable(app.NodeandElementListsNLELTab);
            app.ELTable.ColumnName = {'Element No.'; 'Node 1'; 'Node 2'};
            app.ELTable.RowName = {};
            app.NodeListNLLabel = uilabel(app.NodeandElementListsNLELTab);
            app.NodeListNLLabel.Text = 'Node List (NL)';
            app.ElementListELLabel = uilabel(app.NodeandElementListsNLELTab);
            app.ElementListELLabel.Text = 'Element List (EL)';
            app.BoundaryConditionandMaterialPropertyListsBCsPRsTab = uitab(app.TabGroup);
            app.BoundaryConditionandMaterialPropertyListsBCsPRsTab.Title = 'Boundary Condition and Material Property Lists (BCs &PRs)';
            app.BCsTable = uitable(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.BCsTable.ColumnName = {'Node No.'; 'BC Type (x)'; 'BC Type (y)'; 'Disp. (x)'; 'Disp (y)'; 'Force (x)'; 'Force (y)'};
            app.BCsTable.RowName = {};
            app.PRsTable = uitable(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.PRsTable.ColumnName = {'Element No.'; 'E (Pa)'; 'A (m²)'};
            app.PRsTable.RowName = {};
            app.BoundaryConditionsListBCsLabel = uilabel(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.BoundaryConditionsListBCsLabel.Text = 'Boundary Conditions List (BCs)';
            app.MaterialPropertiesListPRsLabel = uilabel(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.MaterialPropertiesListPRsLabel.Text = 'Material Properties List (PRs)';
            app.ExtendedNodeListENLTab = uitab(app.TabGroup);
            app.ExtendedNodeListENLTab.Title = 'Extended Node List (ENL)';
            app.ENLTable = uitable(app.ExtendedNodeListENLTab);
            app.ENLTable.ColumnName = {'Node No.'; 'NL (x)'; 'NL (y)'; 'BC (x)'; 'BC (y)'; 'Temp. Degree (x)'; 'Temp. Degree (y)'; 'Global Degree (x)'; 'Global Degree (y)'; 'Disp. (x)'; 'Disp (y)'; 'Force (x)'; 'Force (y)'};
            app.ENLTable.RowName = {};
            app.ExtendedNodeListENLLabel = uilabel(app.ExtendedNodeListENLTab);
            app.ExtendedNodeListENLLabel.Text = 'Extended Node List (ENL)';
            app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab = uitab(app.TabGroup);
            app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab.Title = 'Average Mechanical Properties of Typical Engineering Materials';
            app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsLabel = uilabel(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsLabel.Text = 'Average Mechanical Properties of Typical Engineering Materials';
            app.Table_ceramics = uitable(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.Table_ceramics.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_ceramics.RowName = {};
            app.Table_polymers = uitable(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.Table_polymers.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_polymers.RowName = {};
            app.Table_metals = uitable(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.Table_metals.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_metals.RowName = {};
            app.MetalsLabel = uilabel(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.MetalsLabel.Text = 'Metals';
            app.PolymersatroomtemperatureLabel = uilabel(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.PolymersatroomtemperatureLabel.Text = 'Polymers (at room temperature)';
            app.CeramicsandGlassesLabel = uilabel(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.CeramicsandGlassesLabel.Text = 'Ceramics and Glasses';
            app.Label = uilabel(app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.Label.Text = {'[1] R. C. Hibbeler, Mechanics of Materials. 10th ed. United Kingdom: Pearson Education, 2018.'; '[2] N. E. Dowling, Mechanical Behavior of Materials. 4th ed. '};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_example

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end