classdef GUI_project_2v7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ReadNodeandElementListMenu      matlab.ui.container.Menu
        SaveNodeandElementListMenu      matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        QuickDesignTab                  matlab.ui.container.Tab
        QuickDesignModeLabel            matlab.ui.control.Label
        NodeLabelsSwitch_2              matlab.ui.control.ToggleSwitch
        NodeLabelsSwitch_2Label         matlab.ui.control.Label
        ElementLabelsSwitch_2           matlab.ui.control.ToggleSwitch
        ElementLabelsSwitch_2Label      matlab.ui.control.Label
        ZoomDropDown_2Label             matlab.ui.control.Label
        ZoomDropDown_2                  matlab.ui.control.DropDown
        AxisRatioDropDown_2Label        matlab.ui.control.Label
        AxisRatioDropDown_2             matlab.ui.control.DropDown
        MarkerSizeSlider_2              matlab.ui.control.Slider
        MarkerSizeSlider_2Label         matlab.ui.control.Label
        ElementsLabel_2                 matlab.ui.control.Label
        GenerateElementButton_2         matlab.ui.control.Button
        NodesLabel_2                    matlab.ui.control.Label
        GenerateNodeButton_2            matlab.ui.control.Button
        RemoveFeaturePanel_2            matlab.ui.container.Panel
        ClearStructure_2                matlab.ui.control.Button
        NodeEditFieldLabel_2            matlab.ui.control.Label
        DeleteNodenum_2                 matlab.ui.control.NumericEditField
        ElementEditFieldLabel_3         matlab.ui.control.Label
        DeleteElementnum_2              matlab.ui.control.NumericEditField
        DeleteNodeButton_2              matlab.ui.control.Button
        DeleteElementButton_2           matlab.ui.control.Button
        X_coor_2                        matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        Y_coor_2                        matlab.ui.control.NumericEditField
        YLabel_2                        matlab.ui.control.Label
        Node1_2                         matlab.ui.control.NumericEditField
        Node1EditFieldLabel_2           matlab.ui.control.Label
        Node2_2                         matlab.ui.control.NumericEditField
        Node2EditFieldLabel_2           matlab.ui.control.Label
        ResetAllButton_2                matlab.ui.control.Button
        RUNButton_2                     matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        TotalMassWkgLabel_2             matlab.ui.control.Label
        MassCounter_2                   matlab.ui.control.NumericEditField
        NodesCreated_2                  matlab.ui.control.NumericEditField
        NodesEditFieldLabel_2           matlab.ui.control.Label
        ElementsCreated_2               matlab.ui.control.NumericEditField
        ElementsEditFieldLabel_2        matlab.ui.control.Label
        InfoButton_2                    matlab.ui.control.Button
        SplineDrawingToolPanel_2        matlab.ui.container.Panel
        Node1EditFieldLabel_4           matlab.ui.control.Label
        Node1_Sp_2                      matlab.ui.control.NumericEditField
        Node2EditFieldLabel_4           matlab.ui.control.Label
        Node2_Sp_2                      matlab.ui.control.NumericEditField
        AngleEditFieldLabel_2           matlab.ui.control.Label
        Angle_Sp_2                      matlab.ui.control.NumericEditField
        MaxElementLengthLabel_2         matlab.ui.control.Label
        SP_MaxLengthSlider_2            matlab.ui.control.Slider
        SP_FillButton_2                 matlab.ui.control.Button
        ShowSwitchLabel_2               matlab.ui.control.Label
        SP_ShowSwitch_2                 matlab.ui.control.Switch
        UIAxes_2                        matlab.ui.control.UIAxes
        SandboxTab                      matlab.ui.container.Tab
        NodeLabelsSwitch                matlab.ui.control.ToggleSwitch
        NodeLabels                      matlab.ui.control.Label
        ElementLabelsSwitch             matlab.ui.control.ToggleSwitch
        ElementLabels                   matlab.ui.control.Label
        MarkerSizeSlider                matlab.ui.control.Slider
        MarkerSizeSliderLabel           matlab.ui.control.Label
        Node2                           matlab.ui.control.NumericEditField
        Node2EditFieldLabel             matlab.ui.control.Label
        Node1                           matlab.ui.control.NumericEditField
        Node1EditFieldLabel             matlab.ui.control.Label
        ElementsLabel                   matlab.ui.control.Label
        GenerateElementButton           matlab.ui.control.Button
        ElementsCreated                 matlab.ui.control.NumericEditField
        ElementsEditFieldLabel          matlab.ui.control.Label
        Y_coor                          matlab.ui.control.NumericEditField
        YLabel                          matlab.ui.control.Label
        X_coor                          matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        NodesLabel                      matlab.ui.control.Label
        GenerateNodeButton              matlab.ui.control.Button
        NodesCreated                    matlab.ui.control.NumericEditField
        NodesEditFieldLabel             matlab.ui.control.Label
        SandboxModeLabel                matlab.ui.control.Label
        BoundaryConditionsPanel         matlab.ui.container.Panel
        NodeNumberEditFieldLabel        matlab.ui.control.Label
        BCNode                          matlab.ui.control.NumericEditField
        DirectionSwitchLabel            matlab.ui.control.Label
        BCDirectionSwitch               matlab.ui.control.Switch
        TypeSwitchLabel                 matlab.ui.control.Label
        BCTypeSwitch                    matlab.ui.control.Switch
        MagnitudeNormEditFieldLabel     matlab.ui.control.Label
        BCMagnitude                     matlab.ui.control.NumericEditField
        ClearBCs                        matlab.ui.control.Button
        ApplyBCs                        matlab.ui.control.Button
        MaterialPropertiesPanel         matlab.ui.container.Panel
        ElasticityModulus               matlab.ui.control.NumericEditField
        EPaLabel                        matlab.ui.control.Label
        Area                            matlab.ui.control.NumericEditField
        AmLabel                         matlab.ui.control.Label
        PRtoElement                     matlab.ui.control.NumericEditField
        ElementEditFieldLabel           matlab.ui.control.Label
        PRSwitch                        matlab.ui.control.Switch
        AddPR                           matlab.ui.control.Button
        ClearPRs                        matlab.ui.control.Button
        Density                         matlab.ui.control.NumericEditField
        DensitygcmLabel                 matlab.ui.control.Label
        MakeDefButton                   matlab.ui.control.Button
        ResetAllButton                  matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        RemoveFeaturePanel              matlab.ui.container.Panel
        ClearStructure                  matlab.ui.control.Button
        NodeEditFieldLabel              matlab.ui.control.Label
        DeleteNodenum                   matlab.ui.control.NumericEditField
        ElementEditFieldLabel_2         matlab.ui.control.Label
        DeleteElementnum                matlab.ui.control.NumericEditField
        DeleteNodeButton                matlab.ui.control.Button
        DeleteElementButton             matlab.ui.control.Button
        AxisRatioLabel                  matlab.ui.control.Label
        AxisRatioDropDown               matlab.ui.control.DropDown
        RiverProfilePanel               matlab.ui.container.Panel
        ChannelWidthmLabel              matlab.ui.control.Label
        Rwidth                          matlab.ui.control.NumericEditField
        ChannelDepthmLabel              matlab.ui.control.Label
        Rheight                         matlab.ui.control.NumericEditField
        WettedDepthmLabel               matlab.ui.control.Label
        WaterDepth                      matlab.ui.control.NumericEditField
        CreateRiverButton               matlab.ui.control.Button
        ZoomDropDownLabel               matlab.ui.control.Label
        ZoomDropDown                    matlab.ui.control.DropDown
        TotalMassWkgLabel               matlab.ui.control.Label
        MassCounter                     matlab.ui.control.NumericEditField
        InfoButton                      matlab.ui.control.Button
        ModeSwitch                      matlab.ui.control.Switch
        SplineDrawingToolPanel          matlab.ui.container.Panel
        Node1EditFieldLabel_3           matlab.ui.control.Label
        Node1_Sp                        matlab.ui.control.NumericEditField
        Node2EditFieldLabel_3           matlab.ui.control.Label
        Node2_Sp                        matlab.ui.control.NumericEditField
        AngleEditFieldLabel             matlab.ui.control.Label
        Angle_Sp                        matlab.ui.control.NumericEditField
        MaxElementLengthLabel           matlab.ui.control.Label
        SP_MaxLengthSlider              matlab.ui.control.Slider
        SP_FillButton                   matlab.ui.control.Button
        ShowSwitchLabel                 matlab.ui.control.Label
        SP_ShowSwitch                   matlab.ui.control.Switch
        UIAxes                          matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        ColorbarButtonGroup             matlab.ui.container.ButtonGroup
        Jet                             matlab.ui.control.ToggleButton
        Copper                          matlab.ui.control.ToggleButton
        Cool                            matlab.ui.control.ToggleButton
        ResultsLabel                    matlab.ui.control.Label
        ShowRefSwitch_S                 matlab.ui.control.ToggleSwitch
        ShowRefSwitchLabel              matlab.ui.control.Label
        ShowRefSwitch_D                 matlab.ui.control.ToggleSwitch
        ShowRefSwitch_2Label            matlab.ui.control.Label
        ExaggerateSlider                matlab.ui.control.Slider
        ExaggerateSliderLabel           matlab.ui.control.Label
        DispDirecton                    matlab.ui.control.DropDown
        DisplacementabsoluteLabel       matlab.ui.control.Label
        StressLabel                     matlab.ui.control.Label
        WxmaxKgmLabel                   matlab.ui.control.Label
        OptParamCounter_max             matlab.ui.control.NumericEditField
        ShowRiverSwitchLabel            matlab.ui.control.Label
        ShowRiverSwitch                 matlab.ui.control.ToggleSwitch
        WxcenterKgmLabel                matlab.ui.control.Label
        OptParamCounter_cent            matlab.ui.control.NumericEditField
        UIAxes_S                        matlab.ui.control.UIAxes
        UIAxes_D                        matlab.ui.control.UIAxes
        NodeandElementListsNLELTab      matlab.ui.container.Tab
        NLTable                         matlab.ui.control.Table
        ELTable                         matlab.ui.control.Table
        NodeListNLLabel                 matlab.ui.control.Label
        ElementListELLabel              matlab.ui.control.Label
        BoundaryConditionandMaterialPropertyListsBCsPRsTab  matlab.ui.container.Tab
        BCsTable                        matlab.ui.control.Table
        PRsTable                        matlab.ui.control.Table
        BoundaryConditionsListBCsLabel  matlab.ui.control.Label
        MaterialPropertiesListPRsLabel  matlab.ui.control.Label
        ExtendedNodeListENLTab          matlab.ui.container.Tab
        ENLTable                        matlab.ui.control.Table
        ExtendedNodeListENLLabel        matlab.ui.control.Label
        PropertiesofTypicalEngineeringMaterialsTab  matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        TensilePropertiesTab            matlab.ui.container.Tab
        Table_metals                    matlab.ui.control.Table
        RefLabel1                       matlab.ui.control.Label
        Table_polymers                  matlab.ui.control.Table
        AverageTensilePropertiesofTypicalEngineeringMaterialsLabel  matlab.ui.control.Label
        MetalsLabel                     matlab.ui.control.Label
        PolymersatroomtemperatureLabel  matlab.ui.control.Label
        CeramicsandGlassesLabel         matlab.ui.control.Label
        Table_ceramics                  matlab.ui.control.Table
        DensityTab                      matlab.ui.container.Tab
        Table_metals_2                  matlab.ui.control.Table
        RefLabel1_2                     matlab.ui.control.Label
        Table_polymers_2                matlab.ui.control.Table
        RoomTemperatureDensityValuesforTypicalEngineeringMaterialsLabel  matlab.ui.control.Label
        MetalsLabel_2                   matlab.ui.control.Label
        PolymersLabel                   matlab.ui.control.Label
        CeramicsandGlassesLabel_2       matlab.ui.control.Label
        Table_ceramics_2                matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        ExamplesofUnstabilityTab        matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        Image2                          matlab.ui.control.Image
        Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel  matlab.ui.control.Label
        Image3                          matlab.ui.control.Image
        Image4                          matlab.ui.control.Image
        Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_2  matlab.ui.control.Label
        Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_3  matlab.ui.control.Label
        Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_4  matlab.ui.control.Label
        EXAMPLESOFUNSTABILITYLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        NL = [0 0; 10 0]        % Node List
        EL = []                 % Element List
        BCs = [-1 -1 0 0 0 0; -1 -1 0 0 0 0]                % Boundary Conditions List (Type, Disp. and Force), Dimensions = (Number of BCs) x (3*PD)
        ENL = []                % Extended Node List
        DOFs = 0                % Degrees of Freedom
        DOCs = 0                % Degrees of Constraint
        NoN = 2                 % Number of Nodes 
        NoE = 0                 % Number of Elements
        NodeSwitch = "On"       % Node flag switch
        NodeSize = 20           % Size of the Nodes
        ElementSwitch = "On"    %...
        ElementSize = 20        %...
        ar = 1                  % Aspect ratio of the graph
        
        BC_Node = 1             % Node to be assigned BC
        BC_Direction = [1 0]    % Direction of the BC to be assigned
        BC_Type = 1             % Type of the BC to be assigned
        BC_Magnitude = 0        % Magnitude of the BC to be assigned
        
        E = 0                   % Young's Modulus
        A = 0                   % Cross sectional area
        rho = 0                 % Density
        E_def = 210*10^9        % Default value of Young's Modulus given to each new element (in Pa)
        A_def = 0.01*0.01       % Default value of Cross sectional area given to each new element (in m^2)
        rho_def = 8             % Default value of Density given to each new element (in g/cm^3)
        PR_Element = 1          % No. of chosen element to assign material property
        PR_option = "All"       % Option to assign material property to a chosen element OR all elements (all/chosen)
        PRs = []                % Material Properties [NoE x 3], column1 = E, column2 = A and column3 = rho values
        colorMap = jet          % colorMap for plotting
        
        Fp = []                 % Assembled forces
        Up = []                 % Assembled displacements
        Def_mag = 0             % Exaggeration magnitude in displacement plot
        Ref_S = "On"            % Closes/opens reference figure in stress plot
        Ref_D = "On"            % Closes/opens reference figure in displacement plot
        Ref_R = 'On'            % Closes/opens river as a reference in the result plots
        isrun = "off"           % Value shows if algorithm is run or not
        Disp_Direction = "U_t"  % Displacement direction in the plot
        
        L = 10                  % Channel width of the river
        b = 5                   % Max. depth of the river (from level surface to the river bed)
        a = 5                   % Half of the width of the river (important parameter which parametrizing the elliptic curve in which the river shape is defined)
        w = -0.65               % Wetted depth (as a fraction of the max. depth of the river)
        z = 1                   % Zoom multiplier for the graph
        
        S_mode = '0'            % Checks if the user is in sandbox mode
        
        Spline = '0'            % Checks if the user used spline tool
        SP_list = []            % The coordiantes of the node that is about to be created with spline
        SP_list2 = [0 0]        % Node numbers that are about to be connected with spline
        curv_ax = []            % X axis for drawing spline curve
        curv_prof = []          % Curve profile of the spline
        L_max = 1               % Max. length of the spline elements
        SP_theta = 0            % Angle of the spline curve ( arctan(y/x) )
        SP_length = 10          % Horizontal distance between 1st and end nodes of the spline
        
        
    end
    
    methods (Access = public)
        
        function bridgeoverriverkwai = drawbridge(app)
            
            %%% Plotting of the river base %%%
            
            x1 = linspace(-app.L/2, 3*app.L/2, 500);
            x2 = linspace(-app.L/2, 0, 125);
            x3 = linspace(app.L, 3*app.L/2, 375);
            x4 = linspace(0, app.L, 250);
            x5 = linspace(app.L/2 - app.a*sqrt(1-app.w^2), app.L/2 + app.a*sqrt(1-app.w^2), 250);
            curve1 = zeros(1,size(x1,2))-1.15*app.b;
            curve2 = zeros(1,size(x2,2));
            curve3 = zeros(1,size(x3,2));
            curve4 = -app.b*sqrt(1-((x4-app.L/2).^2)/(app.a^2));
            curve5 = zeros(1,size(x5,2))+app.w*app.b;
            
            grid(app.UIAxes,'on')
            hold(app.UIAxes,"on");
            plot(app.UIAxes, x1, curve1, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes, x2, curve2, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes, x3, curve3, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes, x4, curve4, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes, x5, curve5, 'b', 'LineWidth', 2);
            xlim(app.UIAxes,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            
            grid(app.UIAxes_2,'on')
            hold(app.UIAxes_2,"on");
            plot(app.UIAxes_2, x1, curve1, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes_2, x2, curve2, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes_2, x3, curve3, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes_2, x4, curve4, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
            plot(app.UIAxes_2, x5, curve5, 'b', 'LineWidth', 2);
            xlim(app.UIAxes_2,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes_2,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            
            X1 = [x4( :, (app.L/2 - app.a*sqrt(1-app.w^2) < x4(1,:)) &(x4(1,:) < app.L/2 + app.a*sqrt(1-app.w^2))), fliplr(x5)];
            inBetween1 = [-app.b*sqrt(1-((x4( :, (app.L/2 - app.a*sqrt(1-app.w^2) < x4(1,:)) &(x4(1,:) < app.L/2 + app.a*sqrt(1-app.w^2)))-app.L/2).^2)/(app.a^2)), fliplr(curve5)];
            X2 = [x4, fliplr(x4)];
            inBetween2 = [curve1(1:size(x4,2)), fliplr(curve4)];
            X3 = [x2, fliplr(x2)];
            inBetween3 = [curve1(1:size(x2,2)), fliplr(curve2)];
            X4 = [x3, fliplr(x3)];
            inBetween4 = [curve1(1:size(x3,2)), fliplr(curve3)];
            
            fill(app.UIAxes, X1, inBetween1, 'b');
            fill(app.UIAxes, X2, inBetween2, [139/255 69/255 19/255]);
            fill(app.UIAxes, X3, inBetween3, [139/255 69/255 19/255]);
            fill(app.UIAxes, X4, inBetween4, [139/255 69/255 19/255]);
            
            fill(app.UIAxes_2, X1, inBetween1, 'b');
            fill(app.UIAxes_2, X2, inBetween2, [139/255 69/255 19/255]);
            fill(app.UIAxes_2, X3, inBetween3, [139/255 69/255 19/255]);
            fill(app.UIAxes_2, X4, inBetween4, [139/255 69/255 19/255]);
        end
        
        function results = updateAxes(app)
            
                                                          %%%%%%%%%%%%%%%%% Updates/creates the pre-process plot %%%%%%%%%%%%%%%%%
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            drawbridge(app); 
            hold(app.UIAxes,"on");
            hold(app.UIAxes_2,"on");
                        
            if app.Spline == '1'
                plot(app.UIAxes, app.curv_ax, app.curv_prof,'color',[0.7,0.7,0.7],'LineWidth',5)
                plot(app.UIAxes_2, app.curv_ax, app.curv_prof,'color',[0.7,0.7,0.7],'LineWidth',5)
            end 
            
            for i = 1: app.NoE
                % Plots element lines
                
                H=plot(app.UIAxes,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                H.Color(4)=0.2;
                
                H2=plot(app.UIAxes_2,[app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                H2.Color(4)=0.2;
            end
            
            
            switch app.NodeSwitch
                
                case "On"
                    
                    for i = 1:app.NoN
                        % Plots Nodes and label number
                        
                        if (app.BCs(i,1) == -1) && (app.BCs(i,2) == -1)
                            plot(app.UIAxes, app.NL(i,1), app.NL(i,2),'s','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            plot(app.UIAxes_2, app.NL(i,1), app.NL(i,2),'s','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                        
                        elseif app.BCs(i,1) == -1
                            plot(app.UIAxes, app.NL(i,1), app.NL(i,2),'>','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            plot(app.UIAxes_2, app.NL(i,1), app.NL(i,2),'>','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            
                        elseif app.BCs(i,2) == -1
                            plot(app.UIAxes, app.NL(i,1), app.NL(i,2),'^','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            plot(app.UIAxes_2, app.NL(i,1), app.NL(i,2),'^','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            
                        else
                            plot(app.UIAxes, app.NL(i,1), app.NL(i,2),'o','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                            plot(app.UIAxes_2, app.NL(i,1), app.NL(i,2),'o','MarkerSize',app.NodeSize,'MarkerEdgeColor','k','MarkerFaceColor','r')
                        end
                        
                        %axis(app.UIAxes, 'square')
                        text(app.UIAxes,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                        text(app.UIAxes_2,app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
                        
                        x_min = min(app.NL(:,1))-0.25*(max(app.NL(:,1))-min(app.NL(:,1))+0.1);
                        x_max = max(app.NL(:,1))+0.25*(max(app.NL(:,1))-min(app.NL(:,1))+0.1);
                        y_min = min(app.NL(:,2))-0.25*(max(app.NL(:,2))-min(app.NL(:,2))+0.1);
                        y_max = max(app.NL(:,2))+0.25*(max(app.NL(:,2))-min(app.NL(:,2))+0.1);
                        
                        %xlim(app.UIAxes,[x_min,x_max])
                        %ylim(app.UIAxes,[y_min,y_max])
                        w = 0.14*app.NodeSize/20; % width of triangle for BCs (scales with the nodes)
                        sx = abs(x_max-x_min); % how much is x axis distorted wrt zoom
                        sy = abs(y_max-y_min); % how much is y axis is distorted wrt zoom
                        
                        if app.BCs(i,1) == -1
                            
                            if app.BCs(i,3) == 0
%                                 x = [app.NL(i,1)-(w*sx)/2 app.NL(i,1) app.NL(i,1)-(w*sx)/2]; %x coordinates of vertices scaled wrt plot limits to maintain shape
%                                 y = [app.NL(i,2)-(w*sy)/2 app.NL(i,2) app.NL(i,2)+(w*sy)/2]; %y coordinates of vertices scaled wrt plot limits to maintain shape
%                                 patch(app.UIAxes, x, y, 'red', 'FaceColor', 'blue','FaceAlpha', 0.3)
                            else
                                
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), append('\fontsize{35}\rightarrow', '{\fontsize{14}', num2str(app.BCs(i,3)), ' m}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                                text(app.UIAxes_2,app.NL(i,1),app.NL(i,2), append('\fontsize{35}\rightarrow', '{\fontsize{14}', num2str(app.BCs(i,3)), ' m}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                            end
                        
                        end
                        
                        if app.BCs(i,2) == -1
                            
                            if app.BCs(i,4) == 0
%                                 x = [app.NL(i,1)-(w*sx)/2 app.NL(i,1) app.NL(i,1)+(w*sx)/2]; %x coordinates of vertices scaled wrt plot limits to maintain shape
%                                 y = [app.NL(i,2)-(w*sy)/2 app.NL(i,2) app.NL(i,2)-(w*sy)/2]; %y coordinates of vertices scaled wrt plot limits to maintain shape
%                                 patch(app.UIAxes, x, y, 'red', 'FaceColor', 'blue','FaceAlpha', 0.3)
                                
                            else
                                text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{14}', num2str(-1*app.BCs(i,4)),' m'),'\fontsize{35}\downarrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                                text(app.UIAxes_2,app.NL(i,1),app.NL(i,2), {append('\fontsize{14}', num2str(-1*app.BCs(i,4)),' m'),'\fontsize{35}\downarrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            end
                        end
                        
                        if (app.BCs(i,5) ~= 0) && (app.BCs(i,1) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), append('\fontsize{35}\rightarrow', '{\fontsize{14}', num2str(app.BCs(i,5)), ' N}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                            text(app.UIAxes_2,app.NL(i,1),app.NL(i,2), append('\fontsize{35}\rightarrow', '{\fontsize{14}', num2str(app.BCs(i,5)), ' N}'), 'Color', 'magenta', 'VerticalAlignment','middle', 'HorizontalAlignment','left')
                        end
                        
                        if (app.BCs(i,6) ~= 0) && (app.BCs(i,2) == 1)
                            
                            text(app.UIAxes,app.NL(i,1),app.NL(i,2), {append('\fontsize{14}', num2str(-1*app.BCs(i,6)),' N'),'\fontsize{35}\downarrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                            text(app.UIAxes_2,app.NL(i,1),app.NL(i,2), {append('\fontsize{14}', num2str(-1*app.BCs(i,6)),' N'),'\fontsize{35}\downarrow'}, 'Color', 'magenta', 'VerticalAlignment','bottom', 'HorizontalAlignment','center')
                        end
                        
                    end
            end
            
            switch app.ElementSwitch
                
                case "On"
                    
                    for i = 1:app.NoE
                        
                        plot(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2, (app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,'o','MarkerSize',app.ElementSize,'MarkerEdgeColor','k','MarkerFaceColor',[1,1,0])
                        text(app.UIAxes,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2, (app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,num2str(i),'Color','k','FontSize',12,'HorizontalAlignment','center')
                        plot(app.UIAxes_2,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2, (app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,'o','MarkerSize',app.ElementSize,'MarkerEdgeColor','k','MarkerFaceColor',[1,1,0])
                        text(app.UIAxes_2,(app.NL(app.EL(i,1),1)+app.NL(app.EL(i,2),1))/2, (app.NL(app.EL(i,1),2)+app.NL(app.EL(i,2),2))/2,num2str(i),'Color','k','FontSize',12,'HorizontalAlignment','center')
                    end
            
            end
            
            hold(app.UIAxes,"off");
            hold(app.UIAxes_2,"off");
            
        end
        
        function results2 = process(app)
            
                                                      %%%%%%%%%%%%%%%%% Initiates the main body of calculations (apart from assigning BCs) %%%%%%%%%%%%%%%%%
                                                      
                                                  %%%% Need to add code (or error checks) to clear/update final values (or stop the execution to display error) %%%%
            
            format long
            
            K = assemble_stiffness(app.ENL, app.EL, app.NL, app.PRs);
            
            Fp = assemble_forces(app.ENL, app.NL);
            
            Up = assemble_displacements(app.ENL, app.NL);
            
            Kpu = K(1:app.DOFs,1:app.DOFs);
            Kpp = K(1:app.DOFs,app.DOFs+1:app.DOFs+app.DOCs);
            Kuu = K(app.DOFs+1:app.DOCs+app.DOFs,1:app.DOFs);
            Kup = K(app.DOFs+1:app.DOCs+app.DOFs,app.DOFs+1:app.DOCs+app.DOFs);
            
            F = Fp - Kpp * Up;
            
            Uu = inv(Kpu)*F;
            
            Fu = Kuu*Uu + Kup*Up;
            
            app.ENL = update_nodes(app.ENL,Uu,app.NL,Fu);
            
            NoN = 1:app.NoN;
            app.ENLTable.Data = string([NoN(:) app.ENL]);
            
            disp = sqrt((app.ENL(:,9)).^2 + (app.ENL(:,10)).^2);
            app.OptParamCounter_max.Value = max(disp , [], 'all') * app.MassCounter.Value;
            
            if (ismember(round(app.L/2), round(app.NL(:,1))))
                cent_node = intersect( find( app.NL(:,2)== min(app.NL( find(round(app.NL(:,1))==round(app.L/2)),2 )) ), find(round(app.NL(:,1))==round(app.L/2)));
                if ~isnan(disp(cent_node))
                    app.OptParamCounter_cent.Value = disp(cent_node) * app.MassCounter.Value;
                end
  
            end

            
        end
        
        function results3 = Postprocess(app)
            
                                                         %%%%%%%%%%%%%%%%% Displays and manipulates stress and distribution plots %%%%%%%%%%%%%%%%%
            
            %%%% draw the undeformed configuration %%%%
            
            %clears plots, sets colormaps
            cla(app.UIAxes_S);
            cla(app.UIAxes_D);
            
            colormap(app.UIAxes_S,app.colorMap);
            colormap(app.UIAxes_D,app.colorMap);
            
            hold(app.UIAxes_S,"on");
            hold(app.UIAxes_D,"on");
            grid(app.UIAxes_S,'on')
            grid(app.UIAxes_D,'on')
            
            
            switch app.Ref_R
                %%% Plotting of the river %%%
                
                case 'On'
                    x1 = linspace(-app.L/2, 3*app.L/2, 500);
                    x2 = linspace(-app.L/2, 0, 125);
                    x3 = linspace(app.L, 3*app.L/2, 375);
                    x4 = linspace(0, app.L, 250);
                    x5 = linspace(app.L/2 - app.a*sqrt(1-app.w^2), app.L/2 + app.a*sqrt(1-app.w^2), 250);
                    curve1 = zeros(1,size(x1,2))-1.15*app.b;
                    curve2 = zeros(1,size(x2,2));
                    curve3 = zeros(1,size(x3,2));
                    curve4 = -app.b*sqrt(1-((x4-app.L/2).^2)/(app.a^2));
                    curve5 = zeros(1,size(x5,2))+app.w*app.b;
                    
                    plot(app.UIAxes_S, x1, curve1, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_D, x1, curve1, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_S, x2, curve2, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_D, x2, curve2, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_S, x3, curve3, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_D, x3, curve3, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_S, x4, curve4, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_D, x4, curve4, 'Color',[139/255 69/255 19/255], 'LineWidth', 2);
                    plot(app.UIAxes_S, x5, curve5, 'b', 'LineWidth', 2);
                    plot(app.UIAxes_D, x5, curve5, 'b', 'LineWidth', 2);
                    
                    xlim(app.UIAxes_S,[-0.25*app.L, 1.25*app.L]*app.z)
                    ylim(app.UIAxes_S,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
                    xlim(app.UIAxes_D,[-0.25*app.L, 1.25*app.L]*app.z)
                    ylim(app.UIAxes_D,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
                    
                    X1 = [x4( :, (app.L/2 - app.a*sqrt(1-app.w^2) < x4(1,:)) &(x4(1,:) < app.L/2 + app.a*sqrt(1-app.w^2))), fliplr(x5)];
                    inBetween1 = [-app.b*sqrt(1-((x4( :, (app.L/2 - app.a*sqrt(1-app.w^2) < x4(1,:)) &(x4(1,:) < app.L/2 + app.a*sqrt(1-app.w^2)))-app.L/2).^2)/(app.a^2)), fliplr(curve5)];
                    X2 = [x4, fliplr(x4)];
                    inBetween2 = [curve1(1:size(x4,2)), fliplr(curve4)];
                    X3 = [x2, fliplr(x2)];
                    inBetween3 = [curve1(1:size(x2,2)), fliplr(curve2)];
                    X4 = [x3, fliplr(x3)];
                    inBetween4 = [curve1(1:size(x3,2)), fliplr(curve3)];
                    
                    fill(app.UIAxes_S, X1, inBetween1, 'b');
                    fill(app.UIAxes_D, X1, inBetween1, 'b');
                    fill(app.UIAxes_S, X2, inBetween2, [139/255 69/255 19/255]);
                    fill(app.UIAxes_D, X2, inBetween2, [139/255 69/255 19/255]);
                    fill(app.UIAxes_S, X3, inBetween3, [139/255 69/255 19/255]);
                    fill(app.UIAxes_D, X3, inBetween3, [139/255 69/255 19/255]);
                    fill(app.UIAxes_S, X4, inBetween4, [139/255 69/255 19/255]);
                    fill(app.UIAxes_D, X4, inBetween4, [139/255 69/255 19/255]);
                    
            end
            
            
            % plot undeformed elements and nodes
            switch app.Ref_S
                
                case "On"
                    
                    % plot elements
                    for i = 1: app.NoE
                        H_S=plot(app.UIAxes_S, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                        H_S.Color(4)=0.2;
                                 
                    end
                    
                    % plot nodes
                    for i = 1 : app.NoN
                        plot(app.UIAxes_S, app.NL(i,1),app.NL(i,2),'o','MarkerSize',12,'MarkerEdgeColor','k','MarkerFaceColor','r')
                    end
                    
            end
            
            switch app.Ref_D
                
                case "On"
                    
                    % plot elements
                    for i = 1: app.NoE
                        
                        H_D=plot(app.UIAxes_D, [app.NL(app.EL(i,1),1),app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2),app.NL(app.EL(i,2),2)], 'LineWidth',3,'Color','k');
                        H_D.Color(4)=0.2;
                        
                    end
                    
                    % plot nodes
                    for i = 1 : app.NoN
                        plot(app.UIAxes_D, app.NL(i,1),app.NL(i,2),'o','MarkerSize',12,'MarkerEdgeColor','k','MarkerFaceColor','r')
                    end
                    
            end
            
            
            % plots deformed elements with stress and displacement distribution
            for i = 1 : app.NoE
                
                x = [app.ENL(app.EL(i,1),1)+(10^(app.Def_mag))*app.ENL(app.EL(i,1),9) app.ENL(app.EL(i,2),1)+(10^(app.Def_mag))*app.ENL(app.EL(i,2),9)];
                y = [app.ENL(app.EL(i,1),2)+(10^(app.Def_mag))*app.ENL(app.EL(i,1),10) app.ENL(app.EL(i,2),2)+(10^(app.Def_mag))*app.ENL(app.EL(i,2),10)];
                z = zeros(size(x));
                sigma = app.PRs(i,1) * (sqrt((app.ENL(app.EL(i,1),9)-app.ENL(app.EL(i,2),9))^2 + (app.ENL(app.EL(i,1),10)-app.ENL(app.EL(i,2),10))^2)...
                    /(sqrt((app.ENL(app.EL(i,1),1)-app.ENL(app.EL(i,2),1))^2 + (app.ENL(app.EL(i,1),2)-app.ENL(app.EL(i,2),2))^2)));
                
                colS = [sigma sigma];
                surface(app.UIAxes_S, [x;x],[y;y],[z;z],[colS;colS],'facecol','no','edgecol','interp','linew',4);
                
                if app.Disp_Direction == "U_t"
                    colD = [sqrt((app.ENL(app.EL(i,1),9))^2 + (app.ENL(app.EL(i,1),10))^2) sqrt((app.ENL(app.EL(i,2),9)^2 + (app.ENL(app.EL(i,2),10))^2))];
                    surface(app.UIAxes_D, [x;x],[y;y],[z;z],[colD;colD],'facecol','no','edgecol','interp','linew',4);
                
                elseif app.Disp_Direction == "U_x"
                    
                    colDx = [abs(app.ENL(app.EL(i,1),9)) abs(app.ENL(app.EL(i,2),9))];
                    surface(app.UIAxes_D, [x;x],[y;y],[z;z],[colDx;colDx],'facecol','no','edgecol','interp','linew',4);
                    
                elseif app.Disp_Direction == "U_y"   
                    
                    colDy = [abs(app.ENL(app.EL(i,1),10)) abs(app.ENL(app.EL(i,2),10))];
                    surface(app.UIAxes_D, [x;x],[y;y],[z;z],[colDy;colDy],'facecol','no','edgecol','interp','linew',4);
                    
                end

            end
            
            colorbar(app.UIAxes_S);
            colorbar(app.UIAxes_D);
            
            hold(app.UIAxes_S,"off");
            hold(app.UIAxes_D,"off");
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         
            updateAxes(app);

            %%% Fills in the engineering materials property table %%%
            
            format long
            
            app.Table_metals.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Yield Strength \nσ_y [MPa]'); sprintf('Ultimate Strength\nσ_u [MPa]')};
            app.Table_polymers.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Yield Strength \nσ_y [MPa]'); sprintf('Fracture Strength\nσ_f [MPa]')};
            app.Table_ceramics.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Yield Strength \n(Tension)\nσ_y [MPa]'); sprintf('Yield Strength\n(Compression)\nσ_u [MPa]')};
            
            app.Table_metals_2.ColumnName = {sprintf('Material'); sprintf('Density [g/cm³]')};
            app.Table_polymers_2.ColumnName = {sprintf('Material'); sprintf('Density [g/cm³]')};
            app.Table_ceramics_2.ColumnName = {sprintf('Material'); sprintf('Density [g/cm³]')};
            
            
            app.Table_metals.Data = readtable('Mat. Properties List.xlsx','Range','A1:D17');
            
            t2 = readtable('Mat. Properties List.xlsx','Range','F1:I14');
            t2{2, 3} = {'-'};
            t2{4, 3} = {'-'};
            t2{5, 3} = {'-'};
            t2{7, 3} = {'-'};
            t2{11, 3} = {'-'};
            app.Table_polymers.Data = t2;
            
            t3 = readtable('Mat. Properties List.xlsx','Range','K1:N12');
            t3{2, 4} = {'-'};
            app.Table_ceramics.Data = t3;
            
            app.Table_metals_2.Data = readtable('Mat. Properties List.xlsx','Range','A19:B66');
            app.Table_polymers_2.Data = readtable('Mat. Properties List.xlsx','Range','F16:G46');
            app.Table_ceramics_2.Data = readtable('Mat. Properties List.xlsx','Range','K14:L34');
            
        end

        % Button pushed function: GenerateNodeButton
        function GenerateNodeButtonPushed(app, event)
            
            %%%% Draws node, updates node list %%%%
            
            if (app.S_mode == '1') && (app.Spline == '0')
                nl = [app.X_coor.Value, app.Y_coor.Value];  
                
            elseif (app.S_mode == '0') && (app.Spline == '0')
                nl = [app.X_coor_2.Value, app.Y_coor_2.Value];
                
            else
                nl = [app.SP_list(1), app.SP_list(2)];
            end
            
            
            if app.isrun == "on"
                app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
                app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
                app.isrun = "off";
                app.Lamp.Color = [0.94 0.94 0.94];
                app.Lamp_2.Color = [0.94 0.94 0.94];
                
            end
            
            if app.NoN > 0 && (sum(ismember(app.NL, nl,"rows")) == 1)
                uialert(app.UIFigure,"Node already exists","Input Error")
                
            else
                
                app.NL = [app.NL; nl];
                
                app.NoN = size(app.NL,1);
                
                app.NodesCreated.Value = app.NoN;
                app.NodesCreated_2.Value = app.NoN;
                
                app.BCs = [app.BCs; [1 1 0 0 0 0]];
                
                NoN = 1:app.NoN;
                
                app.NLTable.Data = [NoN(:) app.NL];
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) zeros(app.NoN, 12)]);

                updateAxes(app);
            end

        end

        % Button pushed function: GenerateElementButton
        function GenerateElementButtonPushed(app, event)
            
            %%%% Draws element, updates element list %%%%
            
            if (app.S_mode == '1') && (app.Spline == '0')
                el = [app.Node1.Value, app.Node2.Value];  
                
            elseif (app.S_mode == '0') && (app.Spline == '0')
                el = [app.Node1_2.Value, app.Node2_2.Value];
                
            else
                el = [app.SP_list2(1), app.SP_list2(2)];
                
            end
            
            elementLength = sqrt((app.NL(el(2), 1) - (app.NL(el(1), 1)))^2 + (app.NL(el(2), 2) - (app.NL(el(1), 2)))^2);
            
            if app.isrun == "on"
                app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
                app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
                app.isrun = "off";
                app.Lamp.Color = [0.94 0.94 0.94];
                app.Lamp_2.Color = [0.94 0.94 0.94];
                
                NoN = 1:app.NoN;
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) app.ENL]);
            end
            
            if app.NoN < 2
                
                uialert(app.UIFigure,"You need at least 2 nodes to create an element","Input Error")
            
            elseif app.NoE > 0 && (sum(ismember(app.EL, el, "rows")) == 1 || sum(ismember(app.EL, [app.Node2.Value, app.Node1.Value], "rows")) == 1)
                
                uialert(app.UIFigure,"Element already exists","Input Error")
            
            elseif (app.NoN < el(1)) || (app.NoN < el(2))
                
                uialert(app.UIFigure,"Connection node does not exist","Input Error")
                
            elseif (elementLength) > 3
                uialert(app.UIFigure,"An element can be 3 meters at maximum","Input Error")
             
            else
                
                app.EL = [app.EL; el];
                
                app.NoE = size(app.EL,1);
                
                app.ElementsCreated.Value = app.NoE;
                app.ElementsCreated_2.Value = app.NoE;
                
                app.PRs = [app.PRs; [app.E_def app.A_def app.rho_def]];
                
                app.MassCounter.Value = app.MassCounter.Value + 1000*app.rho_def*app.A_def*elementLength;
                app.MassCounter_2.Value = app.MassCounter.Value;
                
                NoE = 1:app.NoE;
                
                app.ELTable.Data = [NoE(:) app.EL];
                app.PRsTable.Data = [NoE(:) app.PRs];
                
                updateAxes(app);
            end
        end

        % Value changed function: MarkerSizeSlider
        function MarkerSizeSliderValueChanged(app, event)
            
            %%%% Adjusts element and node size %%%%
            
            if app.S_mode == '1'
                app.NodeSize = app.MarkerSizeSlider.Value;
                app.ElementSize = app.MarkerSizeSlider.Value;
                
            else
                app.NodeSize = app.MarkerSizeSlider_2.Value;
                app.ElementSize = app.MarkerSizeSlider_2.Value;
            end
            
            updateAxes(app);
        end

        % Selection changed function: ColorbarButtonGroup
        function ColorbarButtonGroupSelectionChanged(app, event)
            
            %%%% Color map selection buttons %%%%
            
            SelectedButton = app.ColorbarButtonGroup.SelectedObject;
            
            switch SelectedButton
                
                case app.Jet
                    app.colorMap = jet;
                    colormap(app.UIAxes_S,app.colorMap);
                    colormap(app.UIAxes_D,app.colorMap);
                    
                case app.Copper
                    app.colorMap = copper;
                    colormap(app.UIAxes_S,app.colorMap);
                    colormap(app.UIAxes_D,app.colorMap);
                
                case app.Cool
                    app.colorMap = cool;
                    colormap(app.UIAxes_S,app.colorMap);
                    colormap(app.UIAxes_D,app.colorMap);
            end
            
            if app.isrun == "on"
                Postprocess(app);
            end
            
        end

        % Value changed function: NodeLabelsSwitch
        function NodeLabelsSwitchValueChanged(app, event)
            
            %%%% Turns on and off Node Labels %%%%
            
            if app.S_mode == '1'
                app.NodeSwitch = app.NodeLabelsSwitch.Value;
                
            else
                app.NodeSwitch = app.NodeLabelsSwitch_2.Value;
            end
            
            
            if app.NoN > 0
                
                updateAxes(app);
            end
            
            
        end

        % Value changed function: ElementLabelsSwitch
        function ElementLabelsSwitchValueChanged(app, event)
            
            %%%% Turns on and off Element Labels %%%%
            
            if app.S_mode == '1'
                app.ElementSwitch = app.ElementLabelsSwitch.Value;
                
            else
                app.ElementSwitch = app.ElementLabelsSwitch_2.Value;
            end
            
            
            if app.NoE > 0
                
                updateAxes(app);
            end
        end

        % Button pushed function: ClearStructure
        function ClearStructureButtonPushed(app, event)
            
            %%%% Clears all Nodes and Elements %%%%
            
            app.NL = [0 0; app.L 0];
            app.EL = [];
            app.MassCounter.Value = 0;
            app.MassCounter_2.Value = 0;
            
            app.NoN = 2;
            app.NoE = 0;
            app.NodesCreated.Value = 2;
            app.NodesCreated_2.Value = 2;
            app.ElementsCreated.Value = 0;
            app.ElementsCreated_2.Value = 0;
            
            app.BCs = [-1 -1 0 0 0 0; -1 -1 0 0 0 0];
            app.PRs = [];
            app.ENL = [];
            
            app.NLTable.Data = [];
            app.ELTable.Data = [];
            app.PRsTable.Data = [];
            app.BCsTable.Data = [];
            app.ENLTable.Data = [];
            
            updateAxes(app);
        end

        % Value changed function: BCNode
        function BCNodeValueChanged(app, event)
            
            %%%% Node to be assigned a BC %%%%
            
            app.BC_Node = app.BCNode.Value;
            
        end

        % Value changed function: BCDirectionSwitch
        function BCDirectionSwitchValueChanged(app, event)
            
            %%%% Direction of the BC to be assigned %%%%
            
            if app.BCDirectionSwitch.Value == "X"
                app.BC_Direction = [1,0];
            
            else
                app.BC_Direction = [0,1];
                
            end
            
        end

        % Value changed function: BCTypeSwitch
        function BCTypeSwitchValueChanged(app, event)
            
            %%% Type of the BC to be assigned %%%%
            
            if app.BCTypeSwitch.Value == "Force"
                app.BC_Type = 1;
                
            else
                app.BC_Type = -1;
                
            end
            
        end

        % Value changed function: BCMagnitude
        function BCMagnitudeValueChanged(app, event)
            
            %%%% Magnitude of the BC to be assigned %%%%
            
            app.BC_Magnitude = app.BCMagnitude.Value;
            
        end

        % Button pushed function: ClearBCs
        function ClearBCsButtonPushed(app, event)
           
            %%%% Button clears all BCs %%%% 
            app.BCs = [-1 -1 0 0 0 0; -1 -1 0 0 0 0];
            app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
            
            if app.isrun == "on"
                app.isrun = "off";
                app.Lamp.Color = [0.94 0.94 0.94];
                app.Lamp_2.Color = [0.94 0.94 0.94];
            end
            
            for i = 1:(app.NoN-2)
                
                app.BCs(i+2,:)=[1 1 0 0 0 0];
            end
            
            NoN = 1:app.NoN;
            app.BCsTable.Data = [NoN(:) app.BCs];
            app.ENLTable.Data = string([NoN(:) app.ENL]);
            
            updateAxes(app);
        end

        % Button pushed function: ApplyBCs
        function ApplyBCsButtonPushed(app, event)
            
            %%%% Assigns the specified BC %%%%
            
            if app.isrun == "on"
                app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
                app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
                app.isrun = "off";
                app.Lamp.Color = [0.94 0.94 0.94];
                app.Lamp_2.Color = [0.94 0.94 0.94];
            end
            
            if app.NoN < app.BC_Node
                uialert(app.UIFigure,"Node does not exist","Input Error")
                
            else
                [M,I]=max(app.BC_Direction); %Finds whether X or Y indice is entered as BC and gives it in I variable (M gives max value of 1x2 direction matrix which is 1)
                
                app.BCs(app.BC_Node,I) = app.BC_Type;
                if app.BC_Type == 1
                    
                    app.BCs(app.BC_Node,5:6) = app.BCs(app.BC_Node,5:6)+(app.BC_Magnitude)*(app.BC_Direction);
                    app.BCs(app.BC_Node,3:4) = app.BCs(app.BC_Node,3:4).*flip(app.BC_Direction);
                else
                    
                    app.BCs(app.BC_Node,3:4) = app.BCs(app.BC_Node,3:4)+(app.BC_Magnitude)*(app.BC_Direction);
                    app.BCs(app.BC_Node,5:6) = app.BCs(app.BC_Node,5:6).*flip(app.BC_Direction);
                end
                [app.ENL , app.DOFs, app.DOCs] = assign_BCs(app.NL,app.BCs);
                
                NoN = 1:app.NoN;
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) app.ENL]);
                
                updateAxes(app);
                
            end
            
        end

        % Value changed function: ElasticityModulus
        function ElasticityModulusValueChanged(app, event)
            
            %%%% Young's Modulus of chosen (OR ALL) elements %%%%
            
            app.E = app.ElasticityModulus.Value;
            
        end

        % Value changed function: Area
        function AreaValueChanged(app, event)
            
            %%%% Cross sectional area of chosen (OR ALL) elements %%%%
            
            app.A = app.Area.Value;
            
        end

        % Value changed function: Density
        function DensityValueChanged(app, event)
            
            %%% Density value of chosen (OR ALL) elements %%%%
            
            app.rho = app.Density.Value;
            
        end

        % Value changed function: PRtoElement
        function PRtoElementValueChanged(app, event)
            
            %%%% No. of chosen element to assign material property %%%%
            
            app.PR_Element = app.PRtoElement.Value;
            
        end

        % Value changed function: PRSwitch
        function PRSwitchValueChanged(app, event)
            
            %%%% Option to assign material property to a chosen element OR all elements %%%%
            
            app.PR_option = app.PRSwitch.Value;
            
        end

        % Button pushed function: AddPR
        function AddPRButtonPushed(app, event)
            
            %%%% Checks whether all or a specific element is chosen, then assigns the typed property value %%%%
            
            if app.isrun == "on"
                app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
                app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
                app.isrun = "off";
                app.Lamp.Color = [0.94 0.94 0.94];
                app.Lamp_2.Color = [0.94 0.94 0.94];
                
                NoN = 1:app.NoN;
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) app.ENL]);
                updateAxes(app);
            end
            
            if app.E >3.21e13
                uialert(app.UIFigure,"Please enter a lower Young's Modulus value. The " + ...
                    "material with the highest know E value is Carbyne, which is 32100 GPa","Input Error")
            
            else

                if app.PR_option == "All"
                    for i = 1:app.NoE
                        app.PRs(i, :) = [app.E app.A app.rho];
                        
                    end
                    
                else
                    app.PRs(app.PR_Element, :) = [app.E app.A app.rho];
                    
                end
                
                app.MassCounter.Value = 0;
                for i = 1:app.NoE
                    elementLength = sqrt((app.NL(app.EL(i,2), 1) - (app.NL(app.EL(i,1), 1)))^2 + (app.NL(app.EL(i,2), 2) - (app.NL(app.EL(i,1), 2)))^2);
                    app.MassCounter.Value = app.MassCounter.Value + 1000*app.PRs(i,3)*app.PRs(i,2)*elementLength;
                end
                
                NoE = 1:app.NoE;
                app.PRsTable.Data = [NoE(:) app.PRs];
            end

            
        end

        % Button pushed function: ClearPRs
        function ClearPRsButtonPushed(app, event)
            
            %%%% Button clears all material properties %%%%
            
            app.PRs = zeros(app.NoE,3);
            app.PRsTable.Data = [];
            app.MassCounter.Value = 0;

        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            %%%% Runs the process algorithm, lights the lamp plots stress and displacement %%%%
            %%%% Need to add many ERROR CHECKS here!!!%%%%
            
            [app.ENL , app.DOFs, app.DOCs] = assign_BCs(app.NL,app.BCs);
            
            if sum(sum(app.NL(:,[1 2]) == -1)) + app.NoE > app.NoN*2
                
                msg = 'The structure seems to be statically indeterminate. Do you want to proceed?';
                title = 'Confirm Run';
                selection = uiconfirm(app.UIFigure,msg,title,'Options',{'Run','Cancel'},'DefaultOption',2,'CancelOption',2);
                
                if selection == 'Run'
                    if ismember(0,app.PRs)
                        uialert(app.UIFigure,"One or more elements does not have material property","Input Error")
                    else
                        process(app);
                        app.Lamp.Color = [0 1 0];
                        app.Lamp_2.Color = [0 1 0];
                        
                        app.isrun = "on";
                        Postprocess(app);
                    end
                end
                
            else
                if ismember(0,app.PRs)
                    uialert(app.UIFigure,"One or more elements does not have material property","Input Error")
                else
                    process(app);
                    app.Lamp.Color = [0 1 0];
                    app.Lamp_2.Color = [0 1 0];
                    
                    app.isrun = "on";
                    Postprocess(app);
                end
                
            end
            
            
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            
            %%%% Resets the structure, BCs, PRs and calculations %%%%
            
            app.NL = [0 0; app.L 0];
            app.EL = [];
            
            app.E_def = 210*10^9;
            app.A_def = 0.01*0.01;
            app.rho_def = 8;
            app.MassCounter.Value = 0;
            app.MassCounter_2.Value = 0;
            app.OptParamCounter_max.Value = 0;
            app.OptParamCounter_cent.Value = 0;
            
            app.NoN = 2;
            app.NoE = 0;
            app.NodesCreated.Value = 0;
            app.NodesCreated_2.Value = 0;
            app.ElementsCreated.Value = 0;
            app.ElementsCreated_2.Value = 0;
            
            app.BCs = [-1 -1 0 0 0 0; -1 -1 0 0 0 0];
            app.PRs = [];
            app.ENL = [];
            
            app.NLTable.Data = [];
            app.ELTable.Data = [];
            app.PRsTable.Data = [];
            app.BCsTable.Data = [];
            app.ENLTable.Data = [];
            
            app.isrun = "off";
            
            updateAxes(app);
            Postprocess(app);
            
            app.Lamp.Color = [0.94 0.94 0.94];
            app.Lamp_2.Color = [0.94 0.94 0.94];
        end

        % Value changed function: ExaggerateSlider
        function ExaggerateSliderValueChanged(app, event)
            
            %%%% Adjusts exagerration of deflection in plot %%%%
            
            app.Def_mag = app.ExaggerateSlider.Value;
            
            if app.isrun == "on"
                Postprocess(app);
            end
        end

        % Value changed function: ShowRefSwitch_S
        function ShowRefSwitch_SValueChanged(app, event)
            
            %%%% Closes/opens reference figure in stress plot %%%%
            
            app.Ref_S = app.ShowRefSwitch_S.Value;
            
            if app.isrun == "on"
                Postprocess(app);
            end
        end

        % Value changed function: ShowRefSwitch_D
        function ShowRefSwitch_DValueChanged(app, event)
            
            %%%% Closes/opens reference figure in displacement plot %%%%
            
            app.Ref_D = app.ShowRefSwitch_D.Value;
            
            if app.isrun == "on"
                Postprocess(app);
            end
            
        end

        % Value changed function: ShowRiverSwitch
        function ShowRiverSwitchValueChanged(app, event)
            
            %%%% Closes/opens river as a reference in the result plots %%%%
            
            app.Ref_R = app.ShowRiverSwitch.Value;
            
            if app.isrun == "on"
                Postprocess(app);
            end
            
        end

        % Value changed function: DispDirecton
        function DispDirectonValueChanged(app, event)

            %%%% Changes the direction of the shown displacement type (U_t, U_x or U_y) %%%%
            
            app.Disp_Direction = app.DispDirecton.Value;
            
            if app.isrun == "on"
                                
                Postprocess(app);
                
            end
            
        end

        % Menu selected function: ReadNodeandElementListMenu
        function ReadNodeandElementListMenuSelected(app, event)
            [inputfile,~] = uigetfile({'*.xlsx','*.slx'},'Select File to Open');
            
            if ~isequal(inputfile,0)
                
                ResetAllButtonPushed(app)
                app.NL = [];
                app.BCs = [];
                
                nl = readmatrix(inputfile,'Range','A:B');
                el = readmatrix(inputfile,'Range','C:D');
                bcl = readmatrix(inputfile,'Range','E:J');
                prl = readmatrix(inputfile,'Range','K:M');
                    
                for i = 1: size(nl,1)
                    if ~isnan(nl(i,:))
                        app.NL = [app.NL; nl(i,:)];
                        app.BCs = [app.BCs; bcl(i,:)];

                    end
                end
                
                for i = 1: size(el,1)
                    if ~isnan(el(i,:))
                        
                        app.PRs = [app.PRs; prl(i,:)];
                        app.EL = [app.EL; el(i,:)];
                        
                        elementLength = sqrt((app.NL(el(i,2), 1) - (app.NL(el(i,1), 1)))^2 + (app.NL(el(i,2), 2) - (app.NL(el(i,1), 2)))^2);
                        app.MassCounter.Value = app.MassCounter.Value + 1000*prl(i,3)*prl(i,2)*elementLength;
                    end
                    app.MassCounter_2.Value = app.MassCounter.Value;
                end
                
                [app.ENL , app.DOFs, app.DOCs] = assign_BCs(app.NL,app.BCs);
                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);
                
                NoN = 1:app.NoN;
                NoE = 1:app.NoE;
                
                app.NLTable.Data = [NoN(:) app.NL];
                app.ELTable.Data = [NoE(:) app.EL];
                app.PRsTable.Data = [NoE(:) app.PRs];
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) app.ENL]);
                
                app.NodesCreated.Value = app.NoN;
                app.ElementsCreated.Value = app.NoE;
                app.NodesCreated_2.Value = app.NoN;
                app.ElementsCreated_2.Value = app.NoE;
                
                app.L = abs(app.NL(1,1)-app.NL(2,1));
                updateAxes(app);
            end
        end

        % Menu selected function: SaveNodeandElementListMenu
        function SaveNodeandElementListMenuSelected(app, event)
            
            % Creates and excel file and writes the NL and EL of the current structure into the file
            
            [outputfile,~] = uiputfile({'*.xlsx','*.slx'},'Save File');
            
            if ~isequal(outputfile,0)
                writecell({'Node x' 'Node y' 'Element N1' 'Element N2' 'BC Type x' 'BC Type y' 'Disp. (x)' 'Disp. (y)' ...
                    'Force (x)' 'Force (y)' 'E (Pa)' 'A (m^2)' 'ρ (g/cm^3)'}, append(outputfile,'.xlsx'),'Range','A1');
                writematrix(app.NL, append(outputfile,'.xlsx'),'Range','A2');
                writematrix(app.EL, append(outputfile,'.xlsx'),'Range','C2');
                
                writematrix(app.BCs(:,[1 2]), append(outputfile,'.xlsx'),'Range','E2');
                writematrix(zeros(app.NoN,4), append(outputfile,'.xlsx'),'Range','G2');
                writematrix(app.PRs, append(outputfile,'.xlsx'),'Range','K2');
            end
            
        end

        % Button pushed function: DeleteElementButton
        function DeleteElementButtonPushed(app, event)
            
            if app.S_mode == '1'
                chosenelement = app.DeleteElementnum.Value;
                
            else
                chosenelement = app.DeleteElementnum_2.Value;
            end
            
            
            if app.isrun == "on"
                app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
                app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
                app.isrun = "off";
                app.Lamp.Color = [0.94 0.94 0.94];
                app.Lamp_2.Color = [0.94 0.94 0.94];
                
                NoN = 1:app.NoN;
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) app.ENL]);
            end
            
            if chosenelement > app.NoE || chosenelement < 1
                uialert(app.UIFigure,"Element does not exist","Input Error")
                
            else
                
                elementLength = sqrt((app.NL(app.EL(chosenelement,2), 1) - (app.NL(app.EL(chosenelement,1), 1)))^2 + (app.NL(app.EL(chosenelement,2), 2) - (app.NL(app.EL(chosenelement,1), 2)))^2);
                
                app.MassCounter.Value = app.MassCounter.Value - 1000*app.PRs(chosenelement,3)*app.PRs(chosenelement,2)*elementLength;
                app.MassCounter_2.Value = app.MassCounter.Value;
                
                app.EL(chosenelement,:) = [];
                
                app.NoE = size(app.EL,1);
                
                app.ElementsCreated.Value = app.NoE;
                app.ElementsCreated_2.Value = app.NoE;
                
                app.PRs(chosenelement,:) = [];
                
                NoE = 1:app.NoE;
                
                app.ELTable.Data = [NoE(:) app.EL];
                app.PRsTable.Data = [NoE(:) app.PRs];
                
                updateAxes(app);
            end
            
        end

        % Button pushed function: DeleteNodeButton
        function DeleteNodeButtonPushed(app, event)
            
            if app.S_mode == '1'
                chosennode = app.DeleteNodenum.Value;
                
            else
                chosennode = app.DeleteNodenum_2.Value;
            end
            
            if app.isrun == "on"
                app.ENL(:,[9 10 11 12]) = zeros(app.NoN,4);
                app.BCs(:,[3 4 5 6]) = zeros(app.NoN,4);
                app.isrun = "off";
                app.Lamp.Color = [0.94 0.94 0.94];
                app.Lamp_2.Color = [0.94 0.94 0.94];
            end
            
            if chosennode > app.NoN || chosennode < 1
                uialert(app.UIFigure,"Node does not exist","Input Error")
                
            elseif app.BCs(chosennode,1) == -1
                uialert(app.UIFigure,"Support nodes can not be removed","Input Error")
            
            else
                if app.NoE > 0
                    ind1 = find(app.EL(:,1)==chosennode);
                    ind2 = find(app.EL(:,2)==chosennode);
                    elementindices = cat(1, ind1, ind2);  % Contains the list of elements the soon to be deleted node is connected to %
                    
                    for i = 1:length(elementindices)
                        elementLength = sqrt((app.NL(app.EL(elementindices(i),2), 1) - (app.NL(app.EL(elementindices(i),1), 1)))^2 + (app.NL(app.EL(elementindices(i),2), 2) - (app.NL(app.EL(elementindices(i),1), 2)))^2);
                        app.MassCounter.Value = app.MassCounter.Value - 1000*app.PRs(elementindices(i),3)*app.PRs(elementindices(i),2)*elementLength; 
                    end
                    app.MassCounter_2.Value = app.MassCounter.Value;
          
                    app.EL(elementindices,:) = [];
                    app.NoE = size(app.EL,1);
                    app.ElementsCreated.Value = app.NoE;
                    app.ElementsCreated_2.Value = app.NoE;
                    app.PRs(elementindices,:) = [];
                    
                    app.EL(app.EL>chosennode) = app.EL(app.EL>chosennode) -1;  % Rearranges the numbering of the element connectivity
                    
                    NoE = 1:app.NoE;
                    app.ELTable.Data = [NoE(:) app.EL];
                    app.PRsTable.Data = [NoE(:) app.PRs];
                    app.PRsTable.Data(:,4) = app.PRsTable.Data(:,4)/1000;
                end
                
                app.NL(chosennode,:) = [];
                app.NoN = size(app.NL,1);
                app.NodesCreated.Value = app.NoN;
                app.NodesCreated_2.Value = app.NoN;
                app.BCs(chosennode,:) = [];
                
                NoN = 1:app.NoN;
                
                app.NLTable.Data = [NoN(:) app.NL];
                app.BCsTable.Data = [NoN(:) app.BCs];
                app.ENLTable.Data = string([NoN(:) zeros(app.NoN, 12)]);
                
                updateAxes(app);
            end
            
        end

        % Value changed function: AxisRatioDropDown
        function AxisRatioDropDownValueChanged(app, event)
            %%% Changes the aspect ratio of the graph %%%
            
            if app.S_mode == '1'
                axis_ratio = app.AxisRatioDropDown.Value; 
                
            else
                axis_ratio = app.AxisRatioDropDown_2.Value;
            end
            
      
            switch axis_ratio
                case '1'
                    app.ar = 8/1;
                case '2'
                    app.ar = 4/1;
                case '3'
                    app.ar = 2/1;
                case '4'
                    app.ar = 16/9;
                case '5'
                    app.ar = 16/10;  
                case '6'
                    app.ar = 4/3;
                case '7'
                    app.ar = 1/1;
                case '8'
                    app.ar = 1/2;
                case '9'
                    app.ar = 1/4;
                case '10'
                    app.ar = 1/8;
            end
            
            xlim(app.UIAxes,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            xlim(app.UIAxes_2,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes_2,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            
            xlim(app.UIAxes_S,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes_S,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            xlim(app.UIAxes_D,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes_D,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            
        end

        % Value changed function: Rwidth
        function RwidthValueChanged(app, event)
            app.L = app.Rwidth.Value;
            app.a = app.L/2;
        end

        % Value changed function: Rheight
        function RheightValueChanged(app, event)
            app.b = app.Rheight.Value;
            
        end

        % Value changed function: WaterDepth
        function WaterDepthValueChanged(app, event)
            h_w = app.WaterDepth.Value;
            app.w = (h_w-app.b)/app.b;
        end

        % Button pushed function: CreateRiverButton
        function CreateRiverButtonPushed(app, event)

            if (app.w <= 0) && (app.w >= -1) && (app.L >= 0) && (app.b >= 0)
                ResetAllButtonPushed(app)
                app.NL(2,1) = app.L;
                updateAxes(app);
            else
                uialert(app.UIFigure,"Please enter valid river dimensions","Input Error")
            end
            
        end

        % Value changed function: ZoomDropDown
        function ZoomDropDownValueChanged(app, event)
            
            if app.S_mode == '1'
                zoom_ratio = app.ZoomDropDown.Value;
                
            else
                zoom_ratio = app.ZoomDropDown_2.Value;
            end
            
            
            switch zoom_ratio
                case '1'
                    app.z = 100/25;
                case '2'
                    app.z = 100/50;
                case '3'
                    app.z = 100/100;
                case '4'
                    app.z = 100/200;
                case '5'
                    app.z = 100/500;
                case '6'
                    app.z = 100/1000;
                case '7'
                    app.z = 100/2500;
                case '8'
                    app.z = 100/5000;
                case '9'
                    app.z = 100/10000;
            end
            
            xlim(app.UIAxes,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            xlim(app.UIAxes_2,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes_2,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            
            xlim(app.UIAxes_S,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes_S,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
            xlim(app.UIAxes_D,[-0.25*app.L, 1.25*app.L]*app.z)
            ylim(app.UIAxes_D,[-0.6*app.L/app.ar, 0.9*app.L/app.ar]*app.z)
        end

        % Button pushed function: MakeDefButton
        function MakeDefButtonButtonPushed(app, event)
            app.E_def = app.E;
            app.A_def = app.A;
            app.rho_def = app.rho;
        end

        % Button pushed function: InfoButton
        function InfoButtonPushed(app, event)
            uialert(app.UIFigure,{'Statically unstable: m+r < 2j' 'Statically determinate: m+r = 2j' 'Statically indeterminate: m+r > 2j' ' ' ...
                'Where' 'm: number of elements' 'r: number of reactions (in our case 4)' 'j: number of nodes' ' ' 'Default Values' 'Channel Width = 10m' ...
                'Channel Depth = 5m' 'Wetted Depth = 1.75m' 'Youngs Modulus = 210 GPa' 'Cross-Sectional Area = 1 cm³' 'Density = 8000 kg/m³' ...
                'Force on middle point (for quick design mode) = -100000 N'},"Info",'Icon','info')
            
        end

        % Value changed function: ModeSwitch
        function ModeSwitchValueChanged(app, event)
            app.S_mode = app.ModeSwitch.Value;
            
            ResetAllButtonPushed(app)
            
            if app.S_mode == '1'
                set(app.X_coor,'Enable','on')
                set(app.Y_coor,'Enable','on')
                set(app.GenerateNodeButton,'Enable','on')
                set(app.NodesCreated,'Enable','on')
                set(app.Node1,'Enable','on')
                set(app.Node2,'Enable','on')
                set(app.GenerateElementButton,'Enable','on')
                set(app.ElementsCreated,'Enable','on')
                set(app.RemoveFeaturePanel,'Enable','on')
                set(app.MaterialPropertiesPanel,'Enable','on')
                set(app.BoundaryConditionsPanel,'Enable','on')
                set(app.MassCounter,'Enable','on')
                set(app.ResetAllButton,'Enable','on')
                set(app.RUNButton,'Enable','on')
                set(app.RiverProfilePanel,'Enable','on')
                set(app.NodeLabelsSwitch,'Enable','on')
                set(app.ElementLabelsSwitch,'Enable','on')
                set(app.AxisRatioDropDown,'Enable','on')
                set(app.AxisRatioLabel,'Enable','on')
                set(app.ZoomDropDown,'Enable','on')
                set(app.ZoomDropDownLabel,'Enable','on')
                set(app.MarkerSizeSlider,'Enable','on')
                set(app.MarkerSizeSliderLabel,'Enable','on')
                set(app.SplineDrawingToolPanel,'Enable','on')
                
                set(app.X_coor_2,'Enable','off')
                set(app.Y_coor_2,'Enable','off')
                set(app.GenerateNodeButton_2,'Enable','off')
                set(app.NodesCreated_2,'Enable','off')
                set(app.Node1_2,'Enable','off')
                set(app.Node2_2,'Enable','off')
                set(app.GenerateElementButton_2,'Enable','off')
                set(app.ElementsCreated_2,'Enable','off')
                set(app.RemoveFeaturePanel_2,'Enable','off')
                set(app.MassCounter_2,'Enable','off')
                set(app.ResetAllButton_2,'Enable','off')
                set(app.RUNButton_2,'Enable','off')
                set(app.NodeLabelsSwitch_2,'Enable','off')
                set(app.NodeLabelsSwitch_2Label,'Enable','off')
                set(app.ElementLabelsSwitch_2,'Enable','off')
                set(app.ElementLabelsSwitch_2Label,'Enable','off')
                set(app.AxisRatioDropDown_2,'Enable','off')
                set(app.AxisRatioDropDown_2Label,'Enable','off')
                set(app.ZoomDropDown_2,'Enable','off')
                set(app.ZoomDropDown_2Label,'Enable','off')
                set(app.MarkerSizeSlider_2,'Enable','off')
                set(app.MarkerSizeSlider_2Label,'Enable','off')
                app.SP_ShowSwitch_2.Value = '0';
                
            else
                set(app.X_coor,'Enable','off')
                set(app.Y_coor,'Enable','off')
                set(app.GenerateNodeButton,'Enable','off')
                set(app.NodesCreated,'Enable','off')
                set(app.Node1,'Enable','off')
                set(app.Node2,'Enable','off')
                set(app.GenerateElementButton,'Enable','off')
                set(app.ElementsCreated,'Enable','off')
                set(app.RemoveFeaturePanel,'Enable','off')
                set(app.MaterialPropertiesPanel,'Enable','off')
                set(app.BoundaryConditionsPanel,'Enable','off')
                set(app.MassCounter,'Enable','off')
                set(app.ResetAllButton,'Enable','off')
                set(app.RUNButton,'Enable','off')
                set(app.RiverProfilePanel,'Enable','off')
                set(app.NodeLabelsSwitch,'Enable','off')
                set(app.ElementLabelsSwitch,'Enable','off')
                set(app.AxisRatioDropDown,'Enable','off')
                set(app.AxisRatioLabel,'Enable','off')
                set(app.ZoomDropDown,'Enable','off')
                set(app.ZoomDropDownLabel,'Enable','off')
                set(app.MarkerSizeSlider,'Enable','off')
                set(app.MarkerSizeSliderLabel,'Enable','off')
                set(app.SplineDrawingToolPanel,'Enable','off')
                app.SP_ShowSwitch.Value = '0';
                
                set(app.X_coor_2,'Enable','on')
                set(app.Y_coor_2,'Enable','on')
                set(app.GenerateNodeButton_2,'Enable','on')
                set(app.NodesCreated_2,'Enable','on')
                set(app.Node1_2,'Enable','on')
                set(app.Node2_2,'Enable','on')
                set(app.GenerateElementButton_2,'Enable','on')
                set(app.ElementsCreated_2,'Enable','on')
                set(app.RemoveFeaturePanel_2,'Enable','on')
                set(app.MassCounter_2,'Enable','on')
                set(app.ResetAllButton_2,'Enable','on')
                set(app.RUNButton_2,'Enable','on')
                set(app.NodeLabelsSwitch_2,'Enable','on')
                set(app.NodeLabelsSwitch_2Label,'Enable','on')
                set(app.ElementLabelsSwitch_2,'Enable','on')
                set(app.ElementLabelsSwitch_2Label,'Enable','on')
                set(app.AxisRatioDropDown_2,'Enable','on')
                set(app.AxisRatioDropDown_2Label,'Enable','on')
                set(app.ZoomDropDown_2,'Enable','on')
                set(app.ZoomDropDown_2Label,'Enable','on')
                set(app.MarkerSizeSlider_2,'Enable','on')
                set(app.MarkerSizeSlider_2Label,'Enable','on')
            end
            
        end

        % Button pushed function: GenerateNodeButton_2
        function GenerateNodeButton_2Pushed(app, event)
            
            GenerateNodeButtonPushed(app)
            [app.ENL , app.DOFs, app.DOCs] = assign_BCs(app.NL,app.BCs);
            NoN = 1:app.NoN;
            app.BCsTable.Data = [NoN(:) app.BCs];
            app.ENLTable.Data = string([NoN(:) app.ENL]);
            
        end

        % Button pushed function: GenerateElementButton_2
        function GenerateElementButton_2Pushed(app, event)
            GenerateElementButtonPushed(app)
        end

        % Button pushed function: DeleteNodeButton_2
        function DeleteNodeButton_2Pushed(app, event)
            DeleteNodeButtonPushed(app)
        end

        % Button pushed function: DeleteElementButton_2
        function DeleteElementButton_2Pushed(app, event)
            DeleteElementButtonPushed(app)
        end

        % Button pushed function: ClearStructure_2
        function ClearStructure_2ButtonPushed(app, event)
            ClearStructureButtonPushed(app)
        end

        % Button pushed function: ResetAllButton_2
        function ResetAllButton_2Pushed(app, event)
            ResetAllButtonPushed(app)
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            
            if ismember(round(app.L/2), round(app.NL(:,1)))
                
                cent_node = intersect( find( app.NL(:,2)== min(app.NL( find(round(app.NL(:,1))==round(app.L/2)),2 )) ), find(round(app.NL(:,1))==round(app.L/2)));
                app.BCs(cent_node,6) = -100000;
                app.ENL(cent_node,12) = -100000;
                updateAxes(app)
                RUNButtonPushed(app)
                
            else
                uialert(app.UIFigure,"There is no central node to apply force","Input Error")
            end

            
        end

        % Value changed function: AxisRatioDropDown_2
        function AxisRatioDropDown_2ValueChanged(app, event)
            AxisRatioDropDownValueChanged(app)
            
        end

        % Value changed function: ZoomDropDown_2
        function ZoomDropDown_2ValueChanged(app, event)
            ZoomDropDownValueChanged(app)
            
        end

        % Value changed function: MarkerSizeSlider_2
        function MarkerSizeSlider_2ValueChanged(app, event)
            MarkerSizeSliderValueChanged(app)
            
        end

        % Value changed function: NodeLabelsSwitch_2
        function NodeLabelsSwitch_2ValueChanged(app, event)
            NodeLabelsSwitchValueChanged(app)
            
        end

        % Value changed function: ElementLabelsSwitch_2
        function ElementLabelsSwitch_2ValueChanged(app, event)
            ElementLabelsSwitchValueChanged(app)
            
        end

        % Button pushed function: InfoButton_2
        function InfoButton_2Pushed(app, event)
            InfoButtonPushed(app)
        end

        % Button pushed function: SP_FillButton
        function SP_FillButtonPushed(app, event)
            app.Spline = '1';
            
            if app.S_mode == '1'
                n1 = app.Node1_Sp.Value;
                n2 = app.Node2_Sp.Value;
                theta = app.Angle_Sp.Value;
                
            else
                n1 = app.Node1_Sp_2.Value;
                n2 = app.Node2_Sp_2.Value;
                theta = app.Angle_Sp_2.Value;
            end


            if (app.NL(n1,2) == app.NL(n2,2)) && (app.NL(n1,1) ~= app.NL(n2,1))
                
                if theta <= 45 && theta >= -45
                    L = app.NL(n2,1) - app.NL(n1,1);
                    a = L/2;
                    b = a*tan(theta*pi/180);
                    
                    x = linspace(app.NL(n1,1), app.NL(n2,1), 50*(abs(app.NL(n1,1) - app.NL(n2,1))));
                    y = b*sqrt(1-(((x-a-app.NL(n1,1)).^2)/(a^2))) + app.NL(n2,2);
                    app.curv_ax = x;
                    app.curv_prof = y;
                    
                    L_tot=arclength(x,y);
                    c = app.L_max;
                    N=round(L_tot/c);
                    diff = 10000;
                    while diff > c
                        pt = interparc(N,x,y,'spline');
                        diff=norm(pt(1,:)-pt(2,:));
                        N = N+1;
                    end
                    
                    pt(1,:)=[];
                    pt(end,:)=[];
                    st = [n1,n2];
                    
                    for i = 1:size(pt,1)
                        app.SP_list = pt(i,:);
                        GenerateNodeButtonPushed(app)
                        st = [st(1:end-1),app.NoN,st(end)];
                    end
                    
                    for i = 1:(size(st,2)-1)
                        app.SP_list2(1) = st(i);
                        app.SP_list2(2) = st(i+1);
                        GenerateElementButtonPushed(app)
                    end
                    
                else
                    uialert(app.UIFigure,"Please enter a curve angle between -45 and 45 degrees","Input Error")
                    
                end
                                
                
            else
                uialert(app.UIFigure,"End nodes must be horizontally aligned to draw a spline","Input Error")
            end
            app.Spline = '0';
            
            
        end

        % Value changed function: SP_MaxLengthSlider
        function SP_MaxLengthSliderValueChanged(app, event)
            app.L_max = app.SP_MaxLengthSlider.Value;
            
        end

        % Value changed function: SP_ShowSwitch
        function SP_ShowSwitchValueChanged(app, event)
            
            if app.S_mode == '1'
                app.Spline = app.SP_ShowSwitch.Value;
                n1 = app.Node1_Sp.Value;
                n2 = app.Node2_Sp.Value;
                theta = app.Angle_Sp.Value;
                
            else 
                app.Spline = app.SP_ShowSwitch_2.Value;
                n1 = app.Node1_Sp_2.Value;
                n2 = app.Node2_Sp_2.Value;
                theta = app.Angle_Sp_2.Value;

            end
            
            if (n1 > app.NoN) || (n2 > app.NoN) 
                uialert(app.UIFigure,"One of the end nodes does not exist","Input Error")
                
            else
                
                if (app.NL(n1,2) == app.NL(n2,2)) && (app.NL(n1,1) ~= app.NL(n2,1))
                    L = app.NL(n2,1) - app.NL(n1,1);
                    a = L/2;
                    b = a*tan(theta*pi/180);
                    
                    x = linspace(app.NL(n1,1), app.NL(n2,1), 50*(abs(app.NL(n1,1) - app.NL(n2,1))));
                    y = b*sqrt(1-(((x-a-app.NL(n1,1)).^2)/(a^2))) + app.NL(n2,2);
                    
                    app.curv_ax = x;
                    app.curv_prof = y;
                    
                    updateAxes(app)
                else
                    uialert(app.UIFigure,"End nodes must be horizontally aligned to draw a spline","Input Error")
                    app.SP_ShowSwitch.Value = '0';
                end
                

                if (app.SP_ShowSwitch.Value == '1') || (app.SP_ShowSwitch_2.Value == '1')
                    updateAxes(app)
                    
                else
                    app.curv_ax = [];
                    app.curv_prof = [];
                    updateAxes(app)
                end
                
            end
            app.Spline = '0';

            
        end

        % Value changed function: Angle_Sp
        function Angle_SpValueChanged(app, event)

            SP_ShowSwitchValueChanged(app)
        end

        % Value changed function: Node2_Sp
        function Node2_SpValueChanged(app, event)
            SP_ShowSwitchValueChanged(app)
        end

        % Value changed function: Node1_Sp
        function Node1_SpValueChanged(app, event)
            SP_ShowSwitchValueChanged(app)
        end

        % Value changed function: SP_MaxLengthSlider_2
        function SP_MaxLengthSlider_2ValueChanged(app, event)
            app.L_max = app.SP_MaxLengthSlider_2.Value;
            
        end

        % Value changed function: Node1_Sp_2
        function Node1_Sp_2ValueChanged(app, event)
            SP_ShowSwitchValueChanged(app)
            
        end

        % Value changed function: Node2_Sp_2
        function Node2_Sp_2ValueChanged(app, event)
            SP_ShowSwitchValueChanged(app)
            
        end

        % Value changed function: Angle_Sp_2
        function Angle_Sp_2ValueChanged(app, event)
            SP_ShowSwitchValueChanged(app)
            
        end

        % Value changed function: SP_ShowSwitch_2
        function SP_ShowSwitch_2ValueChanged(app, event)
            SP_ShowSwitchValueChanged(app)
            
        end

        % Button pushed function: SP_FillButton_2
        function SP_FillButton_2Pushed(app, event)
            SP_FillButtonPushed(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'FEM';
            app.UIFigure.Icon = 'm_bilkent.png';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ReadNodeandElementListMenu = uimenu(app.FileMenu);
            app.ReadNodeandElementListMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadNodeandElementListMenuSelected, true);
            app.ReadNodeandElementListMenu.Text = 'Read Node and Element List';
            app.SaveNodeandElementListMenu = uimenu(app.FileMenu);
            app.SaveNodeandElementListMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveNodeandElementListMenuSelected, true);
            app.SaveNodeandElementListMenu.Text = 'Save Node and Element List';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.QuickDesignTab = uitab(app.TabGroup);
            app.QuickDesignTab.Title = 'Quick Design';
            app.QuickDesignModeLabel = uilabel(app.QuickDesignTab);
            app.QuickDesignModeLabel.Text = 'Quick Design Mode';
            app.NodeLabelsSwitch_2 = uiswitch(app.QuickDesignTab, 'toggle');
            app.NodeLabelsSwitch_2.ValueChangedFcn = createCallbackFcn(app, @NodeLabelsSwitch_2ValueChanged, true);
            app.NodeLabelsSwitch_2.Value = 'On';
            app.NodeLabelsSwitch_2Label = uilabel(app.QuickDesignTab);
            app.NodeLabelsSwitch_2Label.Text = {'Node '; 'Labels'};
            app.ElementLabelsSwitch_2 = uiswitch(app.QuickDesignTab, 'toggle');
            app.ElementLabelsSwitch_2.ValueChangedFcn = createCallbackFcn(app, @ElementLabelsSwitch_2ValueChanged, true);
            app.ElementLabelsSwitch_2.Value = 'On';
            app.ElementLabelsSwitch_2Label = uilabel(app.QuickDesignTab);
            app.ElementLabelsSwitch_2Label.Text = {'Element '; 'Labels'};
            app.ZoomDropDown_2Label = uilabel(app.QuickDesignTab);
            app.ZoomDropDown_2Label.Text = 'Zoom';
            app.ZoomDropDown_2 = uidropdown(app.QuickDesignTab);
            app.ZoomDropDown_2.Items = {'25%', '50%', '100%', '200%', '500%', '1000%', '2500%', '5000%', '10000%'};
            app.ZoomDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ZoomDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ZoomDropDown_2ValueChanged, true);
            app.ZoomDropDown_2.Value = '3';
            app.AxisRatioDropDown_2Label = uilabel(app.QuickDesignTab);
            app.AxisRatioDropDown_2Label.Text = 'Axis Ratio';
            app.AxisRatioDropDown_2 = uidropdown(app.QuickDesignTab);
            app.AxisRatioDropDown_2.Items = {'8:1', '4:1', '2:1', '16:9', '16:10', '4:3', '1:1', '1:2', '1:4', '1:8'};
            app.AxisRatioDropDown_2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.AxisRatioDropDown_2.ValueChangedFcn = createCallbackFcn(app, @AxisRatioDropDown_2ValueChanged, true);
            app.AxisRatioDropDown_2.Value = '7';
            app.MarkerSizeSlider_2 = uislider(app.QuickDesignTab);
            app.MarkerSizeSlider_2.Limits = [1 100];
            app.MarkerSizeSlider_2.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSlider_2ValueChanged, true);
            app.MarkerSizeSlider_2.Value = 20;
            app.MarkerSizeSlider_2Label = uilabel(app.QuickDesignTab);
            app.MarkerSizeSlider_2Label.Text = 'Marker Size';
            app.ElementsLabel_2 = uilabel(app.QuickDesignTab);
            app.ElementsLabel_2.Text = 'Elements';
            app.GenerateElementButton_2 = uibutton(app.QuickDesignTab, 'push');
            app.GenerateElementButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButton_2Pushed, true);
            app.GenerateElementButton_2.Text = 'Generate Element';
            app.NodesLabel_2 = uilabel(app.QuickDesignTab);
            app.NodesLabel_2.Text = 'Nodes';
            app.GenerateNodeButton_2 = uibutton(app.QuickDesignTab, 'push');
            app.GenerateNodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButton_2Pushed, true);
            app.GenerateNodeButton_2.Text = {'Generate Node'; ''};
            app.RemoveFeaturePanel_2 = uipanel(app.QuickDesignTab);
            app.RemoveFeaturePanel_2.Title = 'Remove Feature';
            app.ClearStructure_2 = uibutton(app.RemoveFeaturePanel_2, 'push');
            app.ClearStructure_2.ButtonPushedFcn = createCallbackFcn(app, @ClearStructure_2ButtonPushed, true);
            app.ClearStructure_2.Text = 'Clear Structure';
            app.NodeEditFieldLabel_2 = uilabel(app.RemoveFeaturePanel_2);
            app.NodeEditFieldLabel_2.Text = 'Node';
            app.DeleteNodenum_2 = uieditfield(app.RemoveFeaturePanel_2, 'numeric');
            app.ElementEditFieldLabel_3 = uilabel(app.RemoveFeaturePanel_2);
            app.ElementEditFieldLabel_3.Text = 'Element';
            app.DeleteElementnum_2 = uieditfield(app.RemoveFeaturePanel_2, 'numeric');
            app.DeleteNodeButton_2 = uibutton(app.RemoveFeaturePanel_2, 'push');
            app.DeleteNodeButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteNodeButton_2Pushed, true);
            app.DeleteNodeButton_2.Text = 'Delete Node';
            app.DeleteElementButton_2 = uibutton(app.RemoveFeaturePanel_2, 'push');
            app.DeleteElementButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteElementButton_2Pushed, true);
            app.DeleteElementButton_2.Text = 'Delete Element';
            app.X_coor_2 = uieditfield(app.QuickDesignTab, 'numeric');
            app.XLabel_2 = uilabel(app.QuickDesignTab);
            app.XLabel_2.Text = 'X';
            app.Y_coor_2 = uieditfield(app.QuickDesignTab, 'numeric');
            app.YLabel_2 = uilabel(app.QuickDesignTab);
            app.YLabel_2.Text = 'Y';
            app.Node1_2 = uieditfield(app.QuickDesignTab, 'numeric');
            app.Node1EditFieldLabel_2 = uilabel(app.QuickDesignTab);
            app.Node1EditFieldLabel_2.Text = 'Node 1';
            app.Node2_2 = uieditfield(app.QuickDesignTab, 'numeric');
            app.Node2EditFieldLabel_2 = uilabel(app.QuickDesignTab);
            app.Node2EditFieldLabel_2.Text = 'Node 2';
            app.ResetAllButton_2 = uibutton(app.QuickDesignTab, 'push');
            app.ResetAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButton_2Pushed, true);
            app.ResetAllButton_2.Text = 'Reset All';
            app.RUNButton_2 = uibutton(app.QuickDesignTab, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Text = 'RUN';
            app.Lamp_2 = uilamp(app.QuickDesignTab);
            app.TotalMassWkgLabel_2 = uilabel(app.QuickDesignTab);
            app.TotalMassWkgLabel_2.Text = {'Total Mass, W'; '[kg]'};
            app.MassCounter_2 = uieditfield(app.QuickDesignTab, 'numeric');
            app.MassCounter_2.Editable = 'off';
            app.NodesCreated_2 = uieditfield(app.QuickDesignTab, 'numeric');
            app.NodesCreated_2.Editable = 'off';
            app.NodesCreated_2.Value = 2;
            app.NodesEditFieldLabel_2 = uilabel(app.QuickDesignTab);
            app.NodesEditFieldLabel_2.Text = '#Nodes';
            app.ElementsCreated_2 = uieditfield(app.QuickDesignTab, 'numeric');
            app.ElementsCreated_2.Editable = 'off';
            app.ElementsEditFieldLabel_2 = uilabel(app.QuickDesignTab);
            app.ElementsEditFieldLabel_2.Text = '#Elements';
            app.InfoButton_2 = uibutton(app.QuickDesignTab, 'push');
            app.InfoButton_2.ButtonPushedFcn = createCallbackFcn(app, @InfoButton_2Pushed, true);
            app.InfoButton_2.Icon = 'm_info.png';
            app.InfoButton_2.Text = 'Info';
            app.SplineDrawingToolPanel_2 = uipanel(app.QuickDesignTab);
            app.SplineDrawingToolPanel_2.Title = 'Spline Drawing Tool';
            app.Node1EditFieldLabel_4 = uilabel(app.SplineDrawingToolPanel_2);
            app.Node1EditFieldLabel_4.Text = 'Node 1';
            app.Node1_Sp_2 = uieditfield(app.SplineDrawingToolPanel_2, 'numeric');
            app.Node1_Sp_2.ValueChangedFcn = createCallbackFcn(app, @Node1_Sp_2ValueChanged, true);
            app.Node1_Sp_2.Value = 1;
            app.Node2EditFieldLabel_4 = uilabel(app.SplineDrawingToolPanel_2);
            app.Node2EditFieldLabel_4.Text = 'Node 2';
            app.Node2_Sp_2 = uieditfield(app.SplineDrawingToolPanel_2, 'numeric');
            app.Node2_Sp_2.ValueChangedFcn = createCallbackFcn(app, @Node2_Sp_2ValueChanged, true);
            app.Node2_Sp_2.Value = 2;
            app.AngleEditFieldLabel_2 = uilabel(app.SplineDrawingToolPanel_2);
            app.AngleEditFieldLabel_2.Text = 'Angle (°)';
            app.Angle_Sp_2 = uieditfield(app.SplineDrawingToolPanel_2, 'numeric');
            app.Angle_Sp_2.ValueChangedFcn = createCallbackFcn(app, @Angle_Sp_2ValueChanged, true);
            app.MaxElementLengthLabel_2 = uilabel(app.SplineDrawingToolPanel_2);
            app.MaxElementLengthLabel_2.Text = {'Max.'; 'Element'; 'Length'};
            app.SP_MaxLengthSlider_2 = uislider(app.SplineDrawingToolPanel_2);
            app.SP_MaxLengthSlider_2.Limits = [0 3];
            app.SP_MaxLengthSlider_2.ValueChangedFcn = createCallbackFcn(app, @SP_MaxLengthSlider_2ValueChanged, true);
            app.SP_MaxLengthSlider_2.Value = 1;
            app.SP_FillButton_2 = uibutton(app.SplineDrawingToolPanel_2, 'push');
            app.SP_FillButton_2.ButtonPushedFcn = createCallbackFcn(app, @SP_FillButton_2Pushed, true);
            app.SP_FillButton_2.Text = 'Fill';
            app.ShowSwitchLabel_2 = uilabel(app.SplineDrawingToolPanel_2);
            app.ShowSwitchLabel_2.Text = 'Show';
            app.SP_ShowSwitch_2 = uiswitch(app.SplineDrawingToolPanel_2, 'slider');
            app.SP_ShowSwitch_2.ItemsData = {'0', '1'};
            app.SP_ShowSwitch_2.ValueChangedFcn = createCallbackFcn(app, @SP_ShowSwitch_2ValueChanged, true);
            app.SP_ShowSwitch_2.Value = '0';
            app.UIAxes_2 = uiaxes(app.QuickDesignTab);
            xlabel(app.UIAxes_2, 'X (m)')
            ylabel(app.UIAxes_2, 'Y (m)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 1];
            app.SandboxTab = uitab(app.TabGroup);
            app.SandboxTab.Title = 'Sandbox';
            app.NodeLabelsSwitch = uiswitch(app.SandboxTab, 'toggle');
            app.NodeLabelsSwitch.ValueChangedFcn = createCallbackFcn(app, @NodeLabelsSwitchValueChanged, true);
            app.NodeLabelsSwitch.Enable = 'off';
            app.NodeLabelsSwitch.Value = 'On';
            app.NodeLabels = uilabel(app.SandboxTab);
            app.NodeLabels.Text = {'Node '; 'Labels'};
            app.ElementLabelsSwitch = uiswitch(app.SandboxTab, 'toggle');
            app.ElementLabelsSwitch.ValueChangedFcn = createCallbackFcn(app, @ElementLabelsSwitchValueChanged, true);
            app.ElementLabelsSwitch.Enable = 'off';
            app.ElementLabelsSwitch.Value = 'On';
            app.ElementLabels = uilabel(app.SandboxTab);
            app.ElementLabels.Text = {'Element '; 'Labels'};
            app.MarkerSizeSlider = uislider(app.SandboxTab);
            app.MarkerSizeSlider.Limits = [1 100];
            app.MarkerSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider.Enable = 'off';
            app.MarkerSizeSlider.Value = 20;
            app.MarkerSizeSliderLabel = uilabel(app.SandboxTab);
            app.MarkerSizeSliderLabel.Enable = 'off';
            app.MarkerSizeSliderLabel.Text = 'Marker Size';
            app.Node2 = uieditfield(app.SandboxTab, 'numeric');
            app.Node2.Enable = 'off';
            app.Node2EditFieldLabel = uilabel(app.SandboxTab);
            app.Node2EditFieldLabel.Text = 'Node 2';
            app.Node1 = uieditfield(app.SandboxTab, 'numeric');
            app.Node1.Enable = 'off';
            app.Node1EditFieldLabel = uilabel(app.SandboxTab);
            app.Node1EditFieldLabel.Text = 'Node 1';
            app.ElementsLabel = uilabel(app.SandboxTab);
            app.ElementsLabel.Text = 'Elements';
            app.GenerateElementButton = uibutton(app.SandboxTab, 'push');
            app.GenerateElementButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateElementButtonPushed, true);
            app.GenerateElementButton.Enable = 'off';
            app.GenerateElementButton.Text = 'Generate Element';
            app.ElementsCreated = uieditfield(app.SandboxTab, 'numeric');
            app.ElementsCreated.Editable = 'off';
            app.ElementsCreated.Enable = 'off';
            app.ElementsEditFieldLabel = uilabel(app.SandboxTab);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.Y_coor = uieditfield(app.SandboxTab, 'numeric');
            app.Y_coor.Enable = 'off';
            app.YLabel = uilabel(app.SandboxTab);
            app.YLabel.Text = 'Y';
            app.X_coor = uieditfield(app.SandboxTab, 'numeric');
            app.X_coor.Enable = 'off';
            app.XLabel = uilabel(app.SandboxTab);
            app.XLabel.Text = 'X';
            app.NodesLabel = uilabel(app.SandboxTab);
            app.NodesLabel.Text = 'Nodes';
            app.GenerateNodeButton = uibutton(app.SandboxTab, 'push');
            app.GenerateNodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNodeButtonPushed, true);
            app.GenerateNodeButton.Enable = 'off';
            app.GenerateNodeButton.Text = {'Generate Node'; ''};
            app.NodesCreated = uieditfield(app.SandboxTab, 'numeric');
            app.NodesCreated.Editable = 'off';
            app.NodesCreated.Enable = 'off';
            app.NodesCreated.Value = 2;
            app.NodesEditFieldLabel = uilabel(app.SandboxTab);
            app.NodesEditFieldLabel.Text = '#Nodes';
            app.SandboxModeLabel = uilabel(app.SandboxTab);
            app.SandboxModeLabel.Text = 'Sandbox Mode';
            app.BoundaryConditionsPanel = uipanel(app.SandboxTab);
            app.BoundaryConditionsPanel.Enable = 'off';
            app.BoundaryConditionsPanel.Title = 'Boundary Conditions';
            app.NodeNumberEditFieldLabel = uilabel(app.BoundaryConditionsPanel);
            app.NodeNumberEditFieldLabel.Text = 'Node Number';
            app.BCNode = uieditfield(app.BoundaryConditionsPanel, 'numeric');
            app.BCNode.ValueChangedFcn = createCallbackFcn(app, @BCNodeValueChanged, true);
            app.BCNode.Value = 1;
            app.DirectionSwitchLabel = uilabel(app.BoundaryConditionsPanel);
            app.DirectionSwitchLabel.Text = 'Direction';
            app.BCDirectionSwitch = uiswitch(app.BoundaryConditionsPanel, 'slider');
            app.BCDirectionSwitch.Items = {'X', 'Y'};
            app.BCDirectionSwitch.ValueChangedFcn = createCallbackFcn(app, @BCDirectionSwitchValueChanged, true);
            app.BCDirectionSwitch.Value = 'X';
            app.TypeSwitchLabel = uilabel(app.BoundaryConditionsPanel);
            app.TypeSwitchLabel.Text = 'Type';
            app.BCTypeSwitch = uiswitch(app.BoundaryConditionsPanel, 'slider');
            app.BCTypeSwitch.Items = {'Force', 'Disp.'};
            app.BCTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @BCTypeSwitchValueChanged, true);
            app.BCTypeSwitch.Value = 'Force';
            app.MagnitudeNormEditFieldLabel = uilabel(app.BoundaryConditionsPanel);
            app.MagnitudeNormEditFieldLabel.Text = 'Magnitude [N or m]';
            app.BCMagnitude = uieditfield(app.BoundaryConditionsPanel, 'numeric');
            app.BCMagnitude.ValueChangedFcn = createCallbackFcn(app, @BCMagnitudeValueChanged, true);
            app.ClearBCs = uibutton(app.BoundaryConditionsPanel, 'push');
            app.ClearBCs.ButtonPushedFcn = createCallbackFcn(app, @ClearBCsButtonPushed, true);
            app.ClearBCs.Text = 'Clear BCs';
            app.ApplyBCs = uibutton(app.BoundaryConditionsPanel, 'push');
            app.ApplyBCs.ButtonPushedFcn = createCallbackFcn(app, @ApplyBCsButtonPushed, true);
            app.ApplyBCs.Text = 'Apply';
            app.MaterialPropertiesPanel = uipanel(app.SandboxTab);
            app.MaterialPropertiesPanel.Enable = 'off';
            app.MaterialPropertiesPanel.Title = 'Material Properties';
            app.ElasticityModulus = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.ElasticityModulus.ValueChangedFcn = createCallbackFcn(app, @ElasticityModulusValueChanged, true);
            app.EPaLabel = uilabel(app.MaterialPropertiesPanel);
            app.EPaLabel.Text = 'E [Pa]';
            app.Area = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Area.ValueChangedFcn = createCallbackFcn(app, @AreaValueChanged, true);
            app.AmLabel = uilabel(app.MaterialPropertiesPanel);
            app.AmLabel.Text = 'A [m²]';
            app.PRtoElement = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.PRtoElement.ValueChangedFcn = createCallbackFcn(app, @PRtoElementValueChanged, true);
            app.PRtoElement.Value = 1;
            app.ElementEditFieldLabel = uilabel(app.MaterialPropertiesPanel);
            app.ElementEditFieldLabel.Text = 'Element';
            app.PRSwitch = uiswitch(app.MaterialPropertiesPanel, 'slider');
            app.PRSwitch.Items = {'All', 'Chosen'};
            app.PRSwitch.ValueChangedFcn = createCallbackFcn(app, @PRSwitchValueChanged, true);
            app.PRSwitch.Value = 'All';
            app.AddPR = uibutton(app.MaterialPropertiesPanel, 'push');
            app.AddPR.ButtonPushedFcn = createCallbackFcn(app, @AddPRButtonPushed, true);
            app.AddPR.Text = 'Add';
            app.ClearPRs = uibutton(app.MaterialPropertiesPanel, 'push');
            app.ClearPRs.ButtonPushedFcn = createCallbackFcn(app, @ClearPRsButtonPushed, true);
            app.ClearPRs.Text = 'Clear Properties';
            app.Density = uieditfield(app.MaterialPropertiesPanel, 'numeric');
            app.Density.ValueChangedFcn = createCallbackFcn(app, @DensityValueChanged, true);
            app.DensitygcmLabel = uilabel(app.MaterialPropertiesPanel);
            app.DensitygcmLabel.Text = 'Density [g/cm³] ';
            app.MakeDefButton = uibutton(app.MaterialPropertiesPanel, 'push');
            app.MakeDefButton.ButtonPushedFcn = createCallbackFcn(app, @MakeDefButtonButtonPushed, true);
            app.MakeDefButton.Text = 'Make Default';
            app.ResetAllButton = uibutton(app.SandboxTab, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Enable = 'off';
            app.ResetAllButton.Text = 'Reset All';
            app.RUNButton = uibutton(app.SandboxTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.Lamp = uilamp(app.SandboxTab);
            app.RemoveFeaturePanel = uipanel(app.SandboxTab);
            app.RemoveFeaturePanel.Enable = 'off';
            app.RemoveFeaturePanel.Title = 'Remove Feature';
            app.ClearStructure = uibutton(app.RemoveFeaturePanel, 'push');
            app.ClearStructure.ButtonPushedFcn = createCallbackFcn(app, @ClearStructureButtonPushed, true);
            app.ClearStructure.Text = 'Clear Structure';
            app.NodeEditFieldLabel = uilabel(app.RemoveFeaturePanel);
            app.NodeEditFieldLabel.Text = 'Node';
            app.DeleteNodenum = uieditfield(app.RemoveFeaturePanel, 'numeric');
            app.ElementEditFieldLabel_2 = uilabel(app.RemoveFeaturePanel);
            app.ElementEditFieldLabel_2.Text = 'Element';
            app.DeleteElementnum = uieditfield(app.RemoveFeaturePanel, 'numeric');
            app.DeleteNodeButton = uibutton(app.RemoveFeaturePanel, 'push');
            app.DeleteNodeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNodeButtonPushed, true);
            app.DeleteNodeButton.Text = 'Delete Node';
            app.DeleteElementButton = uibutton(app.RemoveFeaturePanel, 'push');
            app.DeleteElementButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteElementButtonPushed, true);
            app.DeleteElementButton.Text = 'Delete Element';
            app.AxisRatioLabel = uilabel(app.SandboxTab);
            app.AxisRatioLabel.Enable = 'off';
            app.AxisRatioLabel.Text = 'Axis Ratio';
            app.AxisRatioDropDown = uidropdown(app.SandboxTab);
            app.AxisRatioDropDown.Items = {'8:1', '4:1', '2:1', '16:9', '16:10', '4:3', '1:1', '1:2', '1:4', '1:8'};
            app.AxisRatioDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.AxisRatioDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisRatioDropDownValueChanged, true);
            app.AxisRatioDropDown.Enable = 'off';
            app.AxisRatioDropDown.Value = '7';
            app.RiverProfilePanel = uipanel(app.SandboxTab);
            app.RiverProfilePanel.Enable = 'off';
            app.RiverProfilePanel.Title = 'River Profile';
            app.ChannelWidthmLabel = uilabel(app.RiverProfilePanel);
            app.ChannelWidthmLabel.Text = 'Channel Width (m)';
            app.Rwidth = uieditfield(app.RiverProfilePanel, 'numeric');
            app.Rwidth.ValueChangedFcn = createCallbackFcn(app, @RwidthValueChanged, true);
            app.Rwidth.Value = 10;
            app.ChannelDepthmLabel = uilabel(app.RiverProfilePanel);
            app.ChannelDepthmLabel.Text = 'Channel Depth (m)';
            app.Rheight = uieditfield(app.RiverProfilePanel, 'numeric');
            app.Rheight.ValueChangedFcn = createCallbackFcn(app, @RheightValueChanged, true);
            app.Rheight.Value = 5;
            app.WettedDepthmLabel = uilabel(app.RiverProfilePanel);
            app.WettedDepthmLabel.Text = 'Wetted Depth (m)';
            app.WaterDepth = uieditfield(app.RiverProfilePanel, 'numeric');
            app.WaterDepth.ValueChangedFcn = createCallbackFcn(app, @WaterDepthValueChanged, true);
            app.WaterDepth.Value = 1.75;
            app.CreateRiverButton = uibutton(app.RiverProfilePanel, 'push');
            app.CreateRiverButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRiverButtonPushed, true);
            app.CreateRiverButton.Text = 'Create';
            app.ZoomDropDownLabel = uilabel(app.SandboxTab);
            app.ZoomDropDownLabel.Enable = 'off';
            app.ZoomDropDownLabel.Text = 'Zoom';
            app.ZoomDropDown = uidropdown(app.SandboxTab);
            app.ZoomDropDown.Items = {'25%', '50%', '100%', '200%', '500%', '1000%', '2500%', '5000%', '10000%'};
            app.ZoomDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ZoomDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoomDropDownValueChanged, true);
            app.ZoomDropDown.Enable = 'off';
            app.ZoomDropDown.Value = '3';
            app.TotalMassWkgLabel = uilabel(app.SandboxTab);
            app.TotalMassWkgLabel.Text = {'Total Mass, W'; '[kg]'};
            app.MassCounter = uieditfield(app.SandboxTab, 'numeric');
            app.MassCounter.Editable = 'off';
            app.MassCounter.Enable = 'off';
            app.InfoButton = uibutton(app.SandboxTab, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @InfoButtonPushed, true);
            app.InfoButton.Icon = 'm_info.png';
            app.InfoButton.Text = 'Info';
            app.ModeSwitch = uiswitch(app.SandboxTab, 'slider');
            app.ModeSwitch.ItemsData = {'0', '1'};
            app.ModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchValueChanged, true);
            app.ModeSwitch.Value = '0';
            app.SplineDrawingToolPanel = uipanel(app.SandboxTab);
            app.SplineDrawingToolPanel.Enable = 'off';
            app.SplineDrawingToolPanel.Title = 'Spline Drawing Tool';
            app.Node1EditFieldLabel_3 = uilabel(app.SplineDrawingToolPanel);
            app.Node1EditFieldLabel_3.Text = 'Node 1';
            app.Node1_Sp = uieditfield(app.SplineDrawingToolPanel, 'numeric');
            app.Node1_Sp.ValueChangedFcn = createCallbackFcn(app, @Node1_SpValueChanged, true);
            app.Node1_Sp.Value = 1;
            app.Node2EditFieldLabel_3 = uilabel(app.SplineDrawingToolPanel);
            app.Node2EditFieldLabel_3.Text = 'Node 2';
            app.Node2_Sp = uieditfield(app.SplineDrawingToolPanel, 'numeric');
            app.Node2_Sp.ValueChangedFcn = createCallbackFcn(app, @Node2_SpValueChanged, true);
            app.Node2_Sp.Value = 2;
            app.AngleEditFieldLabel = uilabel(app.SplineDrawingToolPanel);
            app.AngleEditFieldLabel.Text = 'Angle (°)';
            app.Angle_Sp = uieditfield(app.SplineDrawingToolPanel, 'numeric');
            app.Angle_Sp.ValueChangedFcn = createCallbackFcn(app, @Angle_SpValueChanged, true);
            app.MaxElementLengthLabel = uilabel(app.SplineDrawingToolPanel);
            app.MaxElementLengthLabel.Text = {'Max.'; 'Element'; 'Length'};
            app.SP_MaxLengthSlider = uislider(app.SplineDrawingToolPanel);
            app.SP_MaxLengthSlider.Limits = [0 3];
            app.SP_MaxLengthSlider.ValueChangedFcn = createCallbackFcn(app, @SP_MaxLengthSliderValueChanged, true);
            app.SP_MaxLengthSlider.Value = 1;
            app.SP_FillButton = uibutton(app.SplineDrawingToolPanel, 'push');
            app.SP_FillButton.ButtonPushedFcn = createCallbackFcn(app, @SP_FillButtonPushed, true);
            app.SP_FillButton.Text = 'Fill';
            app.ShowSwitchLabel = uilabel(app.SplineDrawingToolPanel);
            app.ShowSwitchLabel.Text = 'Show';
            app.SP_ShowSwitch = uiswitch(app.SplineDrawingToolPanel, 'slider');
            app.SP_ShowSwitch.ItemsData = {'0', '1'};
            app.SP_ShowSwitch.ValueChangedFcn = createCallbackFcn(app, @SP_ShowSwitchValueChanged, true);
            app.SP_ShowSwitch.Value = '0';
            app.UIAxes = uiaxes(app.SandboxTab);
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 1];
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.ColorbarButtonGroup = uibuttongroup(app.ResultsTab);
            app.ColorbarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorbarButtonGroupSelectionChanged, true);
            app.ColorbarButtonGroup.Title = 'Colorbar';
            app.Jet = uitogglebutton(app.ColorbarButtonGroup);
            app.Jet.Icon = 'm_jet.png';
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Copper = uitogglebutton(app.ColorbarButtonGroup);
            app.Copper.Icon = 'm_copper.png';
            app.Copper.Text = '';
            app.Cool = uitogglebutton(app.ColorbarButtonGroup);
            app.Cool.Icon = 'm_cool.png';
            app.Cool.Text = '';
            app.ResultsLabel = uilabel(app.ResultsTab);
            app.ResultsLabel.Text = 'Results';
            app.ShowRefSwitch_S = uiswitch(app.ResultsTab, 'toggle');
            app.ShowRefSwitch_S.ValueChangedFcn = createCallbackFcn(app, @ShowRefSwitch_SValueChanged, true);
            app.ShowRefSwitch_S.Value = 'On';
            app.ShowRefSwitchLabel = uilabel(app.ResultsTab);
            app.ShowRefSwitchLabel.Text = 'Show Ref.';
            app.ShowRefSwitch_D = uiswitch(app.ResultsTab, 'toggle');
            app.ShowRefSwitch_D.ValueChangedFcn = createCallbackFcn(app, @ShowRefSwitch_DValueChanged, true);
            app.ShowRefSwitch_D.Value = 'On';
            app.ShowRefSwitch_2Label = uilabel(app.ResultsTab);
            app.ShowRefSwitch_2Label.Text = 'Show Ref.';
            app.ExaggerateSlider = uislider(app.ResultsTab);
            app.ExaggerateSlider.Limits = [-2 3];
            app.ExaggerateSlider.MajorTicks = [-2 -1 0 1 2 3];
            app.ExaggerateSlider.MajorTickLabels = {'0.01', '0.1', '1', '10', '100', '1000'};
            app.ExaggerateSlider.ValueChangedFcn = createCallbackFcn(app, @ExaggerateSliderValueChanged, true);
            app.ExaggerateSlider.MinorTicks = [];
            app.ExaggerateSliderLabel = uilabel(app.ResultsTab);
            app.ExaggerateSliderLabel.Text = 'Exaggerate';
            app.DispDirecton = uidropdown(app.ResultsTab);
            app.DispDirecton.Items = {'U_t', 'U_x', 'U_y'};
            app.DispDirecton.ValueChangedFcn = createCallbackFcn(app, @DispDirectonValueChanged, true);
            app.DispDirecton.Value = 'U_t';
            app.DisplacementabsoluteLabel = uilabel(app.ResultsTab);
            app.DisplacementabsoluteLabel.Text = 'Displacement (absolute)';
            app.StressLabel = uilabel(app.ResultsTab);
            app.StressLabel.Text = 'Stress';
            app.WxmaxKgmLabel = uilabel(app.ResultsTab);
            app.WxmaxKgmLabel.Text = {'Wx𝛿 (max)'; '[Kgm]'};
            app.OptParamCounter_max = uieditfield(app.ResultsTab, 'numeric');
            app.OptParamCounter_max.Editable = 'off';
            app.ShowRiverSwitchLabel = uilabel(app.ResultsTab);
            app.ShowRiverSwitchLabel.Text = 'Show River';
            app.ShowRiverSwitch = uiswitch(app.ResultsTab, 'toggle');
            app.ShowRiverSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowRiverSwitchValueChanged, true);
            app.ShowRiverSwitch.Value = 'On';
            app.WxcenterKgmLabel = uilabel(app.ResultsTab);
            app.WxcenterKgmLabel.Text = {'Wx𝛿 (center)'; '[Kgm]'};
            app.OptParamCounter_cent = uieditfield(app.ResultsTab, 'numeric');
            app.OptParamCounter_cent.Editable = 'off';
            app.UIAxes_S = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes_S, 'X (m)')
            ylabel(app.UIAxes_S, 'Y (m)')
            zlabel(app.UIAxes_S, 'Z')
            app.UIAxes_D = uiaxes(app.ResultsTab);
            xlabel(app.UIAxes_D, 'X(m)')
            ylabel(app.UIAxes_D, 'Y(m)')
            zlabel(app.UIAxes_D, 'Z')
            app.NodeandElementListsNLELTab = uitab(app.TabGroup);
            app.NodeandElementListsNLELTab.Title = 'Node and Element Lists (NL & EL)';
            app.NLTable = uitable(app.NodeandElementListsNLELTab);
            app.NLTable.ColumnName = {'Node No.'; 'X'; 'Y'};
            app.NLTable.RowName = {};
            app.ELTable = uitable(app.NodeandElementListsNLELTab);
            app.ELTable.ColumnName = {'Element No.'; 'Node 1'; 'Node 2'};
            app.ELTable.RowName = {};
            app.NodeListNLLabel = uilabel(app.NodeandElementListsNLELTab);
            app.NodeListNLLabel.Text = 'Node List (NL)';
            app.ElementListELLabel = uilabel(app.NodeandElementListsNLELTab);
            app.ElementListELLabel.Text = 'Element List (EL)';
            app.BoundaryConditionandMaterialPropertyListsBCsPRsTab = uitab(app.TabGroup);
            app.BoundaryConditionandMaterialPropertyListsBCsPRsTab.Title = 'Boundary Condition and Material Property Lists (BCs &PRs)';
            app.BCsTable = uitable(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.BCsTable.ColumnName = {'Node No.'; 'BC Type (x)'; 'BC Type (y)'; 'Disp. (x)'; 'Disp (y)'; 'Force (x)'; 'Force (y)'};
            app.BCsTable.RowName = {};
            app.PRsTable = uitable(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.PRsTable.ColumnName = {'Element No.'; 'E (Pa)'; 'A (m²)'; 'Density [g/cm³]'};
            app.PRsTable.RowName = {};
            app.BoundaryConditionsListBCsLabel = uilabel(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.BoundaryConditionsListBCsLabel.Text = 'Boundary Conditions List (BCs)';
            app.MaterialPropertiesListPRsLabel = uilabel(app.BoundaryConditionandMaterialPropertyListsBCsPRsTab);
            app.MaterialPropertiesListPRsLabel.Text = 'Material Properties List (PRs)';
            app.ExtendedNodeListENLTab = uitab(app.TabGroup);
            app.ExtendedNodeListENLTab.Title = 'Extended Node List (ENL)';
            app.ENLTable = uitable(app.ExtendedNodeListENLTab);
            app.ENLTable.ColumnName = {'Node No.'; 'NL (x)'; 'NL (y)'; 'BC (x)'; 'BC (y)'; 'Temp. Degree (x)'; 'Temp. Degree (y)'; 'Global Degree (x)'; 'Global Degree (y)'; 'Disp. (x)'; 'Disp (y)'; 'Force (x)'; 'Force (y)'};
            app.ENLTable.RowName = {};
            app.ExtendedNodeListENLLabel = uilabel(app.ExtendedNodeListENLTab);
            app.ExtendedNodeListENLLabel.Text = 'Extended Node List (ENL)';
            app.PropertiesofTypicalEngineeringMaterialsTab = uitab(app.TabGroup);
            app.PropertiesofTypicalEngineeringMaterialsTab.Title = 'Properties of Typical Engineering Materials';
            app.TabGroup2 = uitabgroup(app.PropertiesofTypicalEngineeringMaterialsTab);
            app.TensilePropertiesTab = uitab(app.TabGroup2);
            app.TensilePropertiesTab.Title = 'Tensile Properties';
            app.Table_metals = uitable(app.TensilePropertiesTab);
            app.Table_metals.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_metals.RowName = {};
            app.RefLabel1 = uilabel(app.TensilePropertiesTab);
            app.RefLabel1.Text = {'[1] Russel C. Hibbeler, Mechanics of Materials. 10th ed. United Kingdom: Pearson Education, 2018. Print.'; '[2] Norman E. Dowling, Mechanical Behavior of Materials. 4th ed. Boston: Pearson Education, 2013. Print.'};
            app.Table_polymers = uitable(app.TensilePropertiesTab);
            app.Table_polymers.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_polymers.RowName = {};
            app.AverageTensilePropertiesofTypicalEngineeringMaterialsLabel = uilabel(app.TensilePropertiesTab);
            app.AverageTensilePropertiesofTypicalEngineeringMaterialsLabel.Text = 'Average Tensile Properties of Typical Engineering Materials';
            app.MetalsLabel = uilabel(app.TensilePropertiesTab);
            app.MetalsLabel.Text = 'Metals';
            app.PolymersatroomtemperatureLabel = uilabel(app.TensilePropertiesTab);
            app.PolymersatroomtemperatureLabel.Text = 'Polymers (at room temperature)';
            app.CeramicsandGlassesLabel = uilabel(app.TensilePropertiesTab);
            app.CeramicsandGlassesLabel.Text = 'Ceramics and Glasses';
            app.Table_ceramics = uitable(app.TensilePropertiesTab);
            app.Table_ceramics.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_ceramics.RowName = {};
            app.DensityTab = uitab(app.TabGroup2);
            app.DensityTab.Title = 'Density';
            app.Table_metals_2 = uitable(app.DensityTab);
            app.Table_metals_2.ColumnName = {'Column 1'; 'Column 2'};
            app.Table_metals_2.RowName = {};
            app.RefLabel1_2 = uilabel(app.DensityTab);
            app.RefLabel1_2.Text = '[3] Callister, William D., and David G. Rethwisch. Materials Science and Engineering. 9th ed. Hoboken, NJ: Wiley, 2015. Print.';
            app.Table_polymers_2 = uitable(app.DensityTab);
            app.Table_polymers_2.ColumnName = {'Column 1'; 'Column 2'};
            app.Table_polymers_2.RowName = {};
            app.RoomTemperatureDensityValuesforTypicalEngineeringMaterialsLabel = uilabel(app.DensityTab);
            app.RoomTemperatureDensityValuesforTypicalEngineeringMaterialsLabel.Text = 'Room-Temperature Density Values for Typical Engineering Materials';
            app.MetalsLabel_2 = uilabel(app.DensityTab);
            app.MetalsLabel_2.Text = 'Metals';
            app.PolymersLabel = uilabel(app.DensityTab);
            app.PolymersLabel.Text = 'Polymers';
            app.CeramicsandGlassesLabel_2 = uilabel(app.DensityTab);
            app.CeramicsandGlassesLabel_2.Text = 'Ceramics and Glasses';
            app.Table_ceramics_2 = uitable(app.DensityTab);
            app.Table_ceramics_2.ColumnName = {'Column 1'; 'Column 2'};
            app.Table_ceramics_2.RowName = {};
            app.Label = uilabel(app.DensityTab);
            app.Label.Enable = 'off';
            app.Label.Visible = 'off';
            app.Label.Text = '21702398';
            app.ExamplesofUnstabilityTab = uitab(app.TabGroup);
            app.ExamplesofUnstabilityTab.Title = 'Examples of Unstability';
            app.Image = uiimage(app.ExamplesofUnstabilityTab);
            app.Image.ImageSource = 'ex11.png';
            app.Image2 = uiimage(app.ExamplesofUnstabilityTab);
            app.Image2.ImageSource = 'ex22.png';
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel = uilabel(app.ExamplesofUnstabilityTab);
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel.Text = {'Example 1: The highlighted panel is rectangular, which causes the system to be unstable.'; '                   Try dividing the panel into rectangular panels.'};
            app.Image3 = uiimage(app.ExamplesofUnstabilityTab);
            app.Image3.ImageSource = 'ex33.png';
            app.Image4 = uiimage(app.ExamplesofUnstabilityTab);
            app.Image4.ImageSource = 'ex44.png';
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_2 = uilabel(app.ExamplesofUnstabilityTab);
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_2.Text = {'Example 2: Although there are no rectangular panels, the system is still unstable due to'; '                   the disconnectivity in the top middle nodes.'};
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_3 = uilabel(app.ExamplesofUnstabilityTab);
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_3.Text = {'Example 3: The disconnectivity in Example 2 was tried to be solved by creating a straight'; '                   path with 2 elements with one node in the middle. However, the added node'; '                   is only supported in one direction, which causes the system to be unstable.'};
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_4 = uilabel(app.ExamplesofUnstabilityTab);
            app.Example1gjkalsjgksjgklsjgkslgjgslsaklkgjkslgLabel_4.Text = {'Example 4: In here, the added node is not supported only in one direction unlike Example 3.'; '                   However, the structure is still unstable because it created a four-sided panel.'; '                   Therefore, any four-sided panels must be avoided in designing.'};
            app.EXAMPLESOFUNSTABILITYLabel = uilabel(app.ExamplesofUnstabilityTab);
            app.EXAMPLESOFUNSTABILITYLabel.Text = 'EXAMPLES OF UNSTABILITY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_project_2v7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end