classdef GUI_project_3v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        GeometryandConditionsTab        matlab.ui.container.Tab
        InclusionShapeButtonGroup       matlab.ui.container.ButtonGroup
        CircleButton                    matlab.ui.control.ToggleButton
        SquareButton                    matlab.ui.control.ToggleButton
        RhombusButton                   matlab.ui.control.ToggleButton
        Image                           matlab.ui.control.Image
        DimensionsandNumberofPartitionsLabel  matlab.ui.control.Label
        a_val                           matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        b_val                           matlab.ui.control.NumericEditField
        bEditFieldLabel                 matlab.ui.control.Label
        c_val                           matlab.ui.control.NumericEditField
        cEditFieldLabel                 matlab.ui.control.Label
        d_val                           matlab.ui.control.NumericEditField
        dEditFieldLabel                 matlab.ui.control.Label
        e_val                           matlab.ui.control.NumericEditField
        eEditFieldLabel                 matlab.ui.control.Label
        PreProcessLabel                 matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        ElementTypeDropDown             matlab.ui.control.DropDown
        ElementTypeDropDownLabel        matlab.ui.control.Label
        GenerateButton                  matlab.ui.control.Button
        NodesCreated                    matlab.ui.control.NumericEditField
        NodesLabel                      matlab.ui.control.Label
        ElementsCreated                 matlab.ui.control.NumericEditField
        ElementsEditFieldLabel          matlab.ui.control.Label
        ProcessPanel                    matlab.ui.container.Panel
        MaterialPropertiesLabel         matlab.ui.control.Label
        EPaEditFieldLabel               matlab.ui.control.Label
        E_val1                          matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        v_val1                          matlab.ui.control.NumericEditField
        EPaEditField_2Label             matlab.ui.control.Label
        E_val2                          matlab.ui.control.NumericEditField
        EditField_2Label                matlab.ui.control.Label
        v_val2                          matlab.ui.control.NumericEditField
        MatrixLabel                     matlab.ui.control.Label
        InclusionLabel                  matlab.ui.control.Label
        DeformationTypeButtonGroup      matlab.ui.container.ButtonGroup
        ExtensionButton                 matlab.ui.control.RadioButton
        ExpansionButton                 matlab.ui.control.RadioButton
        ShearButton                     matlab.ui.control.RadioButton
        Image2                          matlab.ui.control.Image
        Image2_2                        matlab.ui.control.Image
        Image2_3                        matlab.ui.control.Image
        DeformationMagnitudeLabel       matlab.ui.control.Label
        DeformationMagnitudeEditField   matlab.ui.control.NumericEditField
        RUNButton                       matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        ResetAllButton                  matlab.ui.control.Button
        AxisRatioDropDownLabel          matlab.ui.control.Label
        AxisRatioDropDown               matlab.ui.control.DropDown
        ZoomDropDownLabel               matlab.ui.control.Label
        ZoomDropDown                    matlab.ui.control.DropDown
        UIAxes1                         matlab.ui.control.UIAxes
        ResultsTab                      matlab.ui.container.Tab
        PostProcessLabel                matlab.ui.control.Label
        ColorbarButtonGroup             matlab.ui.container.ButtonGroup
        Jet                             matlab.ui.control.ToggleButton
        Bone                            matlab.ui.control.ToggleButton
        Cool                            matlab.ui.control.ToggleButton
        ExaggerateSlider                matlab.ui.control.Slider
        ExaggerateSliderLabel           matlab.ui.control.Label
        DropDown                        matlab.ui.control.DropDown
        PreciseLabel                    matlab.ui.control.Label
        SmoothLabel                     matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        MechanicalPropertiesofTypicalEngineeringMaterialsTab  matlab.ui.container.Tab
        AverageMechanicalPropertiesofTypicalEngineeringMaterialsLabel  matlab.ui.control.Label
        Table_ceramics                  matlab.ui.control.Table
        Table_polymers                  matlab.ui.control.Table
        Table_metals                    matlab.ui.control.Table
        MetalsLabel                     matlab.ui.control.Label
        PolymersatroomtemperatureLabel  matlab.ui.control.Label
        CeramicsandGlassesLabel         matlab.ui.control.Label
        RefLabel1                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        inclusion_type = 'circle'   % Shape of the void/inclusion
        inclusion = 1               % Checks whether inclusion will be filled (1: yes, 0: no)
        element_type = 'D2QU4N'     % Type of the element
        a = 1                       % Dimensions of mesh
        b = 1
        c = 3
        d = 4
        e = 0.2
        PR = [10 0.3; 10 0.3];      % Stores material properties (Columns: E and v, Rows: matrix, inclusion)
        NoN = 0                     % Number of nodes
        NoE = 0                     % Number of elements
        NPE = 4                     % Node per element
        NL = []                     % Node List
        EL = []                     % Element List
        BCs = []                    % Boundary Conditions List (Type, Disp. and Force), Dimensions = (Number of BCs) x (3*PD)
        ENL = []                    % Extended Node List
        Scale = 1                   % Magnitude of the deformation exaggeration on plots
        Mag = 0.1                   % Magnitude of applied deformation
        isrun = "off"               % Value shows if algorithm is run or not
        ismesh = "off"               % Value shows if mesh is run or not
        BC_type = 'Extension'       % Type of the test applied (Extension, Shear, Expansion)
        
        ar = 1                      % Aspect ratio of the graph
        z = 1                       % Zoom multiplier for the graph
        chosen_plot = 'S_xx'        % Chosen value to be shown on result plots (e.g. S_xy, s_yx, d_x)
        colorMap = jet              % colorMap for plotting
        S_xx = []                   % Data for the stress,strain, disp plots (d: displacement, S: stress, s: strain)
        S_xy = []
        S_yx = []
        S_yy = []
        s_xx = []
        s_xy = []
        s_yx = []
        s_yy = []
        d_x = []
        d_y = []
        X = []
        Y= []
        
    end
    
    methods (Access = private)
        
        function result0 = update_mesh(app)
            
            cla(app.UIAxes1);
            hold(app.UIAxes1,"on");
            
            switch app.element_type
                case {'D2TR3N', 'D2TR6N'}
                    
                    for i = 1:app.NoE
                        plot(app.UIAxes1, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                        plot(app.UIAxes1, [app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                        plot(app.UIAxes1, [app.NL(app.EL(i,3),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,1),2)],'m');
                        %x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,2),1) + app.NL(app.EL(i,3),1))/3;
                        %y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,2),2) + app.NL(app.EL(i,3),2))/3;
                        %text(app.UIAxes1, x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                    end
                    
%                     for i = 1:app.NoN
%                         plot(app.UIAxes1, app.NL(i,1),app.NL(i,2),'o','MarkerSize',15, 'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
%                         text(app.UIAxes1, app.NL(i,1),app.NL(i,2), num2str(i), 'Color','w','FontSize',12,'HorizontalAlignment','center')
%                     end
                    
                    
                case {'D2QU4N', 'D2QU8N', 'D2QU9N'}
                    
                    for i = 1:app.NoE
                        plot(app.UIAxes1, [app.NL(app.EL(i,1),1), app.NL(app.EL(i,2),1)], [app.NL(app.EL(i,1),2), app.NL(app.EL(i,2),2)],'m');
                        plot(app.UIAxes1, [app.NL(app.EL(i,2),1), app.NL(app.EL(i,3),1)], [app.NL(app.EL(i,2),2), app.NL(app.EL(i,3),2)],'m');
                        plot(app.UIAxes1, [app.NL(app.EL(i,3),1), app.NL(app.EL(i,4),1)], [app.NL(app.EL(i,3),2), app.NL(app.EL(i,4),2)],'m');
                        plot(app.UIAxes1, [app.NL(app.EL(i,4),1), app.NL(app.EL(i,1),1)], [app.NL(app.EL(i,4),2), app.NL(app.EL(i,1),2)],'m');
%                         x = (app.NL(app.EL(i,1),1) + app.NL(app.EL(i,2),1) + app.NL(app.EL(i,3),1) + app.NL(app.EL(i,4),1))/4;
%                         y = (app.NL(app.EL(i,1),2) + app.NL(app.EL(i,2),2) + app.NL(app.EL(i,3),2) + app.NL(app.EL(i,4),2))/4;
%                         text(app.UIAxes1, x,y,num2str(i), 'Color', 'k', 'FontSize', 12, 'HorizontalAlignment','center')
                    end
                    
                    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                    
%                     for i = 1:app.NoN
%                         plot(app.UIAxes1, app.NL(i,1),app.NL(i,2),'o','MarkerSize',15,'MarkerEdgeColor','k','MarkerFaceColor',[0,0,1])
%                         text(app.UIAxes1, app.NL(i,1),app.NL(i,2),num2str(i),'Color','w','FontSize',12,'HorizontalAlignment','center')
%                     end
                    

            end
            hold(app.UIAxes1,"off");
            
        end
        
        function results = updateAxes(app)
            
            cla(app.UIAxes2);
            cla(app.UIAxes3);
            
                switch app.chosen_plot
                    
                    case 'S_xx'
                        patch(app.UIAxes2,app.X,app.Y,app.S_xx,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.S_xx,'FaceColor','interp');
                    case 'S_xy'
                        patch(app.UIAxes2,app.X,app.Y,app.S_xy,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.S_xy,'FaceColor','interp');
                    case 'S_yx'
                        patch(app.UIAxes2,app.X,app.Y,app.S_yx,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.S_yx,'FaceColor','interp');
                    case 'S_yy'
                        patch(app.UIAxes2,app.X,app.Y,app.S_yy,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.S_yy,'FaceColor','interp');    
                        
                    case 's_xx'
                        patch(app.UIAxes2,app.X,app.Y,app.s_xx,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.s_xx,'FaceColor','interp');
                    case 's_xy'
                        patch(app.UIAxes2,app.X,app.Y,app.s_xy,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.s_xy,'FaceColor','interp');
                    case 's_yx'
                        patch(app.UIAxes2,app.X,app.Y,app.s_yx,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.s_yx,'FaceColor','interp');
                    case 's_yy'
                        patch(app.UIAxes2,app.X,app.Y,app.s_yy,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.s_yy,'FaceColor','interp');
                        
                    case 'd_x'
                        patch(app.UIAxes2,app.X,app.Y,app.d_x,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.d_x,'FaceColor','interp');
                    case 'd_y'
                        patch(app.UIAxes2,app.X,app.Y,app.d_y,'FaceColor','interp');
                        patch(app.UIAxes3,app.X,app.Y,app.d_y,'FaceColor','interp');
                        
                end
                

                colormap(app.UIAxes2,app.colorMap);
                colorbar(app.UIAxes2);
                
                shading(app.UIAxes3,'interp');
                colormap(app.UIAxes3,[0 0 0; app.colorMap]);
                colorbar(app.UIAxes3);
                
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            format long
            
            app.Table_metals.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Poissons Ratio \n (ν_3D)')};
            app.Table_polymers.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Poissons Ratio \n (ν_3D)')};
            app.Table_ceramics.ColumnName = {sprintf('Material'); sprintf('Elastic Modulus\nE [GPa]'); sprintf('Poissons Ratio \n (ν_3D)')};
            
            t1 = readtable('M. Props.xlsx','Range','A2:C51');
            t1{42, 3} = {'-'};
            t1{43, 3} = {'-'};
            t1{44, 3} = {'-'};
            t1{45, 3} = {'-'};
            t1{48, 3} = {'-'};            
            app.Table_metals.Data = t1;
            
            t2 = readtable('M. Props.xlsx','Range','N2:P19');
            t2{1, 3} = {'-'};
            t2{2, 3} = {'-'};
            t2{3, 3} = {'-'};
            t2{5, 3} = {'-'};
            t2{6, 3} = {'-'};
            t2{8, 3} = {'-'};
            t2{9, 3} = {'-'};
            t2{12, 3} = {'-'};
            
            app.Table_polymers.Data = t2;
            
            t3 = readtable('M. Props.xlsx','Range','H2:J25');
            t3{8, 3} = {'-'};
            t3{9, 3} = {'-'};
            t3{13, 3} = {'-'};
            t3{14, 3} = {'-'};
            t3{17, 3} = {'-'};
                        
            app.Table_ceramics.Data = t3;
        end

        % Selection changed function: InclusionShapeButtonGroup
        function InclusionShapeButtonGroupSelectionChanged(app, event)
            selectedButton = app.InclusionShapeButtonGroup.SelectedObject;
            
            if selectedButton == app.CircleButton
                app.Image.ImageSource = 'M_Circ_void.png';
                app.inclusion_type = 'circle';
            
            elseif selectedButton == app.SquareButton
                app.Image.ImageSource = 'M_Square_void.png';
                app.inclusion_type = 'square';
                
            elseif selectedButton == app.RhombusButton
                app.Image.ImageSource = 'M_Rhombus_void2.png';   
                app.inclusion_type = 'rhombu';
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.inclusion = str2num(app.Switch.Value);
            if app.inclusion
                set(app.E_val2,'Enable','on')
                set(app.v_val2,'Enable','on')
            else
                set(app.E_val2,'Enable','off')
                set(app.v_val2,'Enable','off')
            end
            
        end

        % Value changed function: a_val
        function a_valValueChanged(app, event)
            app.a = app.a_val.Value;
            
        end

        % Value changed function: b_val
        function b_valValueChanged(app, event)
            app.b = app.b_val.Value;
            
        end

        % Value changed function: c_val
        function c_valValueChanged(app, event)
            app.c = app.c_val.Value;
            
        end

        % Value changed function: d_val
        function d_valValueChanged(app, event)
            app.d = app.d_val.Value;
            
        end

        % Value changed function: e_val
        function e_valValueChanged(app, event)
            app.e = app.e_val.Value;
            
        end

        % Value changed function: ElementTypeDropDown
        function ElementTypeDropDownValueChanged(app, event)
            app.element_type = app.ElementTypeDropDown.Value;
            
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            if (app.a<0)||(app.b<0)||(app.c<0)||(app.d<0)||(app.e<0)
                uialert(app.UIFigure,"The dimensional parameters need to be positive.","Input Error")
            else
                [app.NL, app.EL] = void_mesh(app.a, app.b, app.c, app.d, app.e, app.element_type, app.inclusion, app.inclusion_type);
                
                app.NoN = size(app.NL,1);
                app.NoE = size(app.EL,1);
                app.NPE = size(app.EL,2);
                app.NodesCreated.Value = app.NoN;
                app.ElementsCreated.Value = app.NoE;
                
                app.ismesh = "on";
                
                update_mesh(app);
            end  
            
        end

        % Value changed function: ExaggerateSlider
        function ExaggerateSliderValueChanged(app, event)
            app.Scale = app.ExaggerateSlider.Value;

            [app.S_xx,app.S_xy,app.S_yx,app.S_yy,app.s_xx,app.s_xy,app.s_yx,app.s_yy,app.d_x,app.d_y, app.X, app.Y]=post_process(app.NL, app.EL, app.ENL, app.PR, app.c, app.d, app.Scale);
            if app.isrun == "on"
                updateAxes(app);
            end
        end

        % Value changed function: DeformationMagnitudeEditField
        function DeformationMagnitudeEditFieldValueChanged(app, event)
            app.Mag = app.DeformationMagnitudeEditField.Value;
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            if app.ismesh == "off"
                uialert(app.UIFigure,"The mesh does not exist.","Input Error")
                
            elseif (app.PR(1,1) >3.21e13)||(app.PR(2,1) >3.21e13)
                uialert(app.UIFigure,"Please enter a lower Young's Modulus value. The " + ...
                    "material with the highest know E value is Carbyne, which is 32100 GPa","Input Error")
                
            elseif (app.PR(1,2) >1)||(app.PR(2,2) >1)
                uialert(app.UIFigure,"Three dimensional Poisson's Ratio can be 0.5 at maximum, which corresponds to an effective Poisson's Ratio of 1. Please enter a value higher than this.","Input Error")
            
            elseif (app.PR(1,1) <0)||(app.PR(1,2) <0)||(app.PR(2,1) <0)||(app.PR(2,2) <0)
                uialert(app.UIFigure,"Material properties can not be negative.","Input Error")
 
            else
                [app.ENL, DOFs, DOCs] = assign_BCs(app.NL, app.BC_type, app.Mag);
                
                K = assemble_stiffness(app.ENL, app.EL, app.NL, app.PR, app.c, app.d);
                
                Fp = assemble_forces(app.ENL, app.NL);
                
                Up = assemble_displacements(app.ENL, app.NL);
                
                Kpu = K(1:DOFs,1:DOFs);
                Kpp = K(1:DOFs,DOFs+1:DOFs+DOCs);
                Kuu = K(DOFs+1:DOCs+DOFs,1:DOFs);
                Kup = K(DOFs+1:DOCs+DOFs,DOFs+1:DOCs+DOFs);
                
                F = Fp - Kpp * Up;
                
                Uu = inv(Kpu)*F;
                
                Fu = Kuu*Uu + Kup*Up;
                
                app.ENL = update_nodes(app.ENL,Uu,app.NL,Fu);
                [app.S_xx,app.S_xy,app.S_yx,app.S_yy,app.s_xx,app.s_xy,app.s_yx,app.s_yy,app.d_x,app.d_y, app.X, app.Y]=post_process(app.NL, app.EL, app.ENL, app.PR, app.c, app.d, app.Scale);
                
                app.Lamp.Color = [0 1 0];
                app.isrun = "on";
                updateAxes(app);
            end
            
        end

        % Selection changed function: DeformationTypeButtonGroup
        function DeformationTypeButtonGroupSelectionChanged(app, event)
            chosen_test = app.DeformationTypeButtonGroup.SelectedObject;
            
            switch chosen_test
                
                case app.ExtensionButton
                    app.BC_type = 'Extension';
                case app.ExpansionButton
                    app.BC_type = 'Expansion';
                case app.ShearButton
                    app.BC_type = 'Shear';    
            end
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.chosen_plot = app.DropDown.Value;
            
            if app.isrun == "on"
                updateAxes(app);
            end
        end

        % Selection changed function: ColorbarButtonGroup
        function ColorbarButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColorbarButtonGroup.SelectedObject;
            
            switch selectedButton
                
                case app.Jet
                    app.colorMap = jet;
                    
                case app.Bone
                    app.colorMap = bone;
                    
                case app.Cool
                    app.colorMap = cool;
            end
            
            if app.isrun == "on"
                updateAxes(app);
            end
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.NL = [];
            app.EL = [];
            
            app.NoN = 0;
            app.NoE = 0;
            app.NodesCreated.Value = 0;
            app.ElementsCreated.Value = 0;
            app.ENL = [];
            
            app.isrun = "off";
            app.ismesh = "off";
            
            updateAxes(app);
            update_mesh(app);
            
            app.Lamp.Color = [0.94 0.94 0.94];
        end

        % Value changed function: E_val1
        function E_val1ValueChanged(app, event)
            app.PR(1,1) = app.E_val1.Value;
            
        end

        % Value changed function: v_val1
        function v_val1ValueChanged(app, event)
            app.PR(1,2) = app.v_val1.Value;
            
        end

        % Value changed function: E_val2
        function E_val2ValueChanged(app, event)
            app.PR(2,1) = app.E_val2.Value;
            
        end

        % Value changed function: v_val2
        function v_val2ValueChanged(app, event)
            app.PR(2,2) = app.v_val2.Value;
            
        end

        % Value changed function: ZoomDropDown
        function ZoomDropDownValueChanged(app, event)
            zoom_ratio = app.ZoomDropDown.Value;

            switch zoom_ratio
                case '1'
                    app.z = 100/25;
                case '2'
                    app.z = 100/50;
                case '3'
                    app.z = 100/100;
                case '4'
                    app.z = 100/200;
                case '5'
                    app.z = 100/500;
                case '6'
                    app.z = 100/1000;
                case '7'
                    app.z = 100/2500;
                case '8'
                    app.z = 100/5000;
                case '9'
                    app.z = 100/10000;
            end
            
            xlim(app.UIAxes1,[-0.1*app.a, 1.1*app.a]*app.z)
            ylim(app.UIAxes1,[-0.1*app.b/app.ar, 1.1*app.b/app.ar]*app.z)
            
            xlim(app.UIAxes2,[-0.1*app.a, 1.1*app.a]*app.z)
            ylim(app.UIAxes2,[-0.1*app.b/app.ar, 1.1*app.b/app.ar]*app.z)    
            xlim(app.UIAxes3,[-0.1*app.a, 1.1*app.a]*app.z)
            ylim(app.UIAxes3,[-0.1*app.b/app.ar, 1.1*app.b/app.ar]*app.z)
            
        end

        % Value changed function: AxisRatioDropDown
        function AxisRatioDropDownValueChanged(app, event)
            axis_ratio = app.AxisRatioDropDown.Value;

            switch axis_ratio
                case '1'
                    app.ar = 8/1;
                case '2'
                    app.ar = 4/1;
                case '3'
                    app.ar = 2/1;
                case '4'
                    app.ar = 16/9;
                case '5'
                    app.ar = 16/10;
                case '6'
                    app.ar = 4/3;
                case '7'
                    app.ar = 1/1;
                case '8'
                    app.ar = 1/2;
                case '9'
                    app.ar = 1/4;
                case '10'
                    app.ar = 1/8;
            end
            
            xlim(app.UIAxes1,[-0.1*app.a, 1.1*app.a]*app.z)
            ylim(app.UIAxes1,[-0.1*app.b/app.ar, 1.1*app.b/app.ar]*app.z)
            
            xlim(app.UIAxes2,[-0.1*app.a, 1.1*app.a]*app.z)
            ylim(app.UIAxes2,[-0.1*app.b/app.ar, 1.1*app.b/app.ar]*app.z)    
            xlim(app.UIAxes3,[-0.1*app.a, 1.1*app.a]*app.z)
            ylim(app.UIAxes3,[-0.1*app.b/app.ar, 1.1*app.b/app.ar]*app.z)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.Icon = 'm_bilkent.png';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.GeometryandConditionsTab = uitab(app.TabGroup);
            app.GeometryandConditionsTab.Title = 'Geometry and Conditions';
            app.InclusionShapeButtonGroup = uibuttongroup(app.GeometryandConditionsTab);
            app.InclusionShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @InclusionShapeButtonGroupSelectionChanged, true);
            app.InclusionShapeButtonGroup.Title = 'Inclusion Shape';
            app.CircleButton = uitogglebutton(app.InclusionShapeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.SquareButton = uitogglebutton(app.InclusionShapeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.RhombusButton = uitogglebutton(app.InclusionShapeButtonGroup);
            app.RhombusButton.Text = 'Rhombus';
            app.Image = uiimage(app.GeometryandConditionsTab);
            app.Image.ImageSource = 'M_Circ_void.png';
            app.DimensionsandNumberofPartitionsLabel = uilabel(app.GeometryandConditionsTab);
            app.DimensionsandNumberofPartitionsLabel.Text = 'Dimensions and Number of Partitions';
            app.a_val = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.a_val.ValueChangedFcn = createCallbackFcn(app, @a_valValueChanged, true);
            app.a_val.Value = 1;
            app.aEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.aEditFieldLabel.Text = 'a';
            app.b_val = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.b_val.ValueChangedFcn = createCallbackFcn(app, @b_valValueChanged, true);
            app.b_val.Value = 1;
            app.bEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.bEditFieldLabel.Text = 'b';
            app.c_val = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.c_val.ValueChangedFcn = createCallbackFcn(app, @c_valValueChanged, true);
            app.c_val.Value = 3;
            app.cEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.cEditFieldLabel.Text = 'c';
            app.d_val = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.d_val.ValueChangedFcn = createCallbackFcn(app, @d_valValueChanged, true);
            app.d_val.Value = 4;
            app.dEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.dEditFieldLabel.Text = 'd';
            app.e_val = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.e_val.ValueChangedFcn = createCallbackFcn(app, @e_valValueChanged, true);
            app.e_val.Value = 0.2;
            app.eEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.eEditFieldLabel.Text = 'e';
            app.PreProcessLabel = uilabel(app.GeometryandConditionsTab);
            app.PreProcessLabel.Text = 'Pre-Process';
            app.Switch = uiswitch(app.GeometryandConditionsTab, 'slider');
            app.Switch.Items = {'Inclusion', 'Void'};
            app.Switch.ItemsData = {'1', '0'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '1';
            app.ElementTypeDropDown = uidropdown(app.GeometryandConditionsTab);
            app.ElementTypeDropDown.Items = {'D2TR3N', 'D2TR6N', 'D2QU4N', 'D2QU8N', 'D2QU9N'};
            app.ElementTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementTypeDropDownValueChanged, true);
            app.ElementTypeDropDown.Value = 'D2QU4N';
            app.ElementTypeDropDownLabel = uilabel(app.GeometryandConditionsTab);
            app.ElementTypeDropDownLabel.Text = 'Element Type';
            app.GenerateButton = uibutton(app.GeometryandConditionsTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.NodesCreated = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.NodesCreated.Editable = 'off';
            app.NodesLabel = uilabel(app.GeometryandConditionsTab);
            app.NodesLabel.Text = '#Nodes';
            app.ElementsCreated = uieditfield(app.GeometryandConditionsTab, 'numeric');
            app.ElementsCreated.Editable = 'off';
            app.ElementsEditFieldLabel = uilabel(app.GeometryandConditionsTab);
            app.ElementsEditFieldLabel.Text = '#Elements';
            app.ProcessPanel = uipanel(app.GeometryandConditionsTab);
            app.ProcessPanel.Title = 'Process';
            app.MaterialPropertiesLabel = uilabel(app.ProcessPanel);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.EPaEditFieldLabel = uilabel(app.ProcessPanel);
            app.EPaEditFieldLabel.Text = 'E [Pa]';
            app.E_val1 = uieditfield(app.ProcessPanel, 'numeric');
            app.E_val1.ValueChangedFcn = createCallbackFcn(app, @E_val1ValueChanged, true);
            app.E_val1.Value = 10;
            app.EditFieldLabel = uilabel(app.ProcessPanel);
            app.EditFieldLabel.Text = 'ν';
            app.v_val1 = uieditfield(app.ProcessPanel, 'numeric');
            app.v_val1.ValueChangedFcn = createCallbackFcn(app, @v_val1ValueChanged, true);
            app.v_val1.Value = 0.3;
            app.EPaEditField_2Label = uilabel(app.ProcessPanel);
            app.EPaEditField_2Label.Text = 'E [Pa]';
            app.E_val2 = uieditfield(app.ProcessPanel, 'numeric');
            app.E_val2.ValueChangedFcn = createCallbackFcn(app, @E_val2ValueChanged, true);
            app.E_val2.Value = 10;
            app.EditField_2Label = uilabel(app.ProcessPanel);
            app.EditField_2Label.Text = 'ν';
            app.v_val2 = uieditfield(app.ProcessPanel, 'numeric');
            app.v_val2.ValueChangedFcn = createCallbackFcn(app, @v_val2ValueChanged, true);
            app.v_val2.Value = 0.3;
            app.MatrixLabel = uilabel(app.ProcessPanel);
            app.MatrixLabel.Text = 'Matrix';
            app.InclusionLabel = uilabel(app.ProcessPanel);
            app.InclusionLabel.Text = 'Inclusion';
            app.DeformationTypeButtonGroup = uibuttongroup(app.ProcessPanel);
            app.DeformationTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DeformationTypeButtonGroupSelectionChanged, true);
            app.DeformationTypeButtonGroup.Title = 'Deformation Type';
            app.ExtensionButton = uiradiobutton(app.DeformationTypeButtonGroup);
            app.ExtensionButton.Text = 'Extension';
            app.ExtensionButton.Value = true;
            app.ExpansionButton = uiradiobutton(app.DeformationTypeButtonGroup);
            app.ExpansionButton.Text = 'Expansion';
            app.ShearButton = uiradiobutton(app.DeformationTypeButtonGroup);
            app.ShearButton.Text = 'Shear';
            app.Image2 = uiimage(app.DeformationTypeButtonGroup);
            app.Image2.ImageSource = 'T_Extension_test.png';
            app.Image2_2 = uiimage(app.DeformationTypeButtonGroup);
            app.Image2_2.ImageSource = 'T_Expansion_test.png';
            app.Image2_3 = uiimage(app.DeformationTypeButtonGroup);
            app.Image2_3.ImageSource = 'T_Shear_test.png';
            app.DeformationMagnitudeLabel = uilabel(app.ProcessPanel);
            app.DeformationMagnitudeLabel.Text = {'Deformation '; 'Magnitude'};
            app.DeformationMagnitudeEditField = uieditfield(app.ProcessPanel, 'numeric');
            app.DeformationMagnitudeEditField.ValueChangedFcn = createCallbackFcn(app, @DeformationMagnitudeEditFieldValueChanged, true);
            app.DeformationMagnitudeEditField.Value = 0.1;
            app.RUNButton = uibutton(app.ProcessPanel, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.Lamp = uilamp(app.ProcessPanel);
            app.ResetAllButton = uibutton(app.ProcessPanel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.AxisRatioDropDownLabel = uilabel(app.GeometryandConditionsTab);
            app.AxisRatioDropDownLabel.Text = 'Axis Ratio';
            app.AxisRatioDropDown = uidropdown(app.GeometryandConditionsTab);
            app.AxisRatioDropDown.Items = {'8:1', '4:1', '2:1', '16:9', '16:10', '4:3', '1:1', '1:2', '1:4', '1:8'};
            app.AxisRatioDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.AxisRatioDropDown.ValueChangedFcn = createCallbackFcn(app, @AxisRatioDropDownValueChanged, true);
            app.AxisRatioDropDown.Value = '7';
            app.ZoomDropDownLabel = uilabel(app.GeometryandConditionsTab);
            app.ZoomDropDownLabel.Text = 'Zoom';
            app.ZoomDropDown = uidropdown(app.GeometryandConditionsTab);
            app.ZoomDropDown.Items = {'25%', '50%', '100%', '200%', '500%', '1000%', '2500%', '5000%', '10000%'};
            app.ZoomDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ZoomDropDown.ValueChangedFcn = createCallbackFcn(app, @ZoomDropDownValueChanged, true);
            app.ZoomDropDown.Value = '3';
            app.UIAxes1 = uiaxes(app.GeometryandConditionsTab);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.PostProcessLabel = uilabel(app.ResultsTab);
            app.PostProcessLabel.Text = 'Post-Process';
            app.ColorbarButtonGroup = uibuttongroup(app.ResultsTab);
            app.ColorbarButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorbarButtonGroupSelectionChanged, true);
            app.ColorbarButtonGroup.Title = 'Colorbar';
            app.Jet = uitogglebutton(app.ColorbarButtonGroup);
            app.Jet.Icon = 'm_jet.png';
            app.Jet.Text = '';
            app.Jet.Value = true;
            app.Bone = uitogglebutton(app.ColorbarButtonGroup);
            app.Bone.Icon = 'm_bone.png';
            app.Bone.Text = '';
            app.Cool = uitogglebutton(app.ColorbarButtonGroup);
            app.Cool.Icon = 'm_cool.png';
            app.Cool.Text = '';
            app.ExaggerateSlider = uislider(app.ResultsTab);
            app.ExaggerateSlider.Limits = [-2 2];
            app.ExaggerateSlider.MajorTicks = [-2 -1 0 1 2];
            app.ExaggerateSlider.MajorTickLabels = {'0.01', '0.1', '1', '10', '100'};
            app.ExaggerateSlider.ValueChangedFcn = createCallbackFcn(app, @ExaggerateSliderValueChanged, true);
            app.ExaggerateSlider.MinorTicks = [];
            app.ExaggerateSliderLabel = uilabel(app.ResultsTab);
            app.ExaggerateSliderLabel.Text = 'Exaggerate';
            app.DropDown = uidropdown(app.ResultsTab);
            app.DropDown.Items = {'Stress_xx', 'Stress_xy', 'Stress_yx', 'Stress_yy', 'Strain_xx', 'Strain_xy', 'Strain_yx', 'Strain_yy', 'Displacement_x', 'Displacement_y', ''};
            app.DropDown.ItemsData = {'S_xx', 'S_xy', 'S_yx', 'S_yy', 's_xx', 's_xy', 's_yx', 's_yy', 'd_x', 'd_y'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'S_xx';
            app.PreciseLabel = uilabel(app.ResultsTab);
            app.PreciseLabel.Text = 'Precise';
            app.SmoothLabel = uilabel(app.ResultsTab);
            app.SmoothLabel.Text = 'Smooth';
            app.UIAxes2 = uiaxes(app.ResultsTab);
            app.UIAxes3 = uiaxes(app.ResultsTab);
            app.MechanicalPropertiesofTypicalEngineeringMaterialsTab = uitab(app.TabGroup);
            app.MechanicalPropertiesofTypicalEngineeringMaterialsTab.Title = 'Mechanical Properties of Typical Engineering Materials';
            app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsLabel = uilabel(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.AverageMechanicalPropertiesofTypicalEngineeringMaterialsLabel.Text = 'Average Mechanical Properties of Typical Engineering Materials';
            app.Table_ceramics = uitable(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.Table_ceramics.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_ceramics.RowName = {};
            app.Table_polymers = uitable(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.Table_polymers.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_polymers.RowName = {};
            app.Table_metals = uitable(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.Table_metals.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Table_metals.RowName = {};
            app.MetalsLabel = uilabel(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.MetalsLabel.Text = 'Metals';
            app.PolymersatroomtemperatureLabel = uilabel(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.PolymersatroomtemperatureLabel.Text = 'Polymers (at room temperature)';
            app.CeramicsandGlassesLabel = uilabel(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.CeramicsandGlassesLabel.Text = 'Ceramics and Glasses';
            app.RefLabel1 = uilabel(app.MechanicalPropertiesofTypicalEngineeringMaterialsTab);
            app.RefLabel1.Text = '[1] Callister, William D., and David G. Rethwisch. Materials Science and Engineering. 9th ed. Hoboken, NJ: Wiley, 2015. Print.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_project_3v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end